classdef test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        MesuredTransformationMatrixLabel  matlab.ui.control.Label
        UITable_4                 matlab.ui.control.Table
        CalculatedTransformationMatrixLabel  matlab.ui.control.Label
        UITable                   matlab.ui.control.Table
        TabGroup                  matlab.ui.container.TabGroup
        EndEffectorTab            matlab.ui.container.Tab
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
        Joint1Tab                 matlab.ui.container.Tab
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        Joint2Tab                 matlab.ui.container.Tab
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        Joint3Tab                 matlab.ui.container.Tab
        UIAxes_12                 matlab.ui.control.UIAxes
        UIAxes_11                 matlab.ui.control.UIAxes
        UIAxes_10                 matlab.ui.control.UIAxes
        Joint4Tab                 matlab.ui.container.Tab
        UIAxes_15                 matlab.ui.control.UIAxes
        UIAxes_14                 matlab.ui.control.UIAxes
        UIAxes_13                 matlab.ui.control.UIAxes
        Joint5Tab                 matlab.ui.container.Tab
        UIAxes_18                 matlab.ui.control.UIAxes
        UIAxes_17                 matlab.ui.control.UIAxes
        UIAxes_16                 matlab.ui.control.UIAxes
        Joint6Tab                 matlab.ui.container.Tab
        UIAxes_21                 matlab.ui.control.UIAxes
        UIAxes_20                 matlab.ui.control.UIAxes
        UIAxes_19                 matlab.ui.control.UIAxes
        InverseValuesPanel        matlab.ui.container.Panel
        Label_13                  matlab.ui.control.Label
        EditField_19              matlab.ui.control.NumericEditField
        Label_12                  matlab.ui.control.Label
        EditField_18              matlab.ui.control.NumericEditField
        Label_11                  matlab.ui.control.Label
        EditField_17              matlab.ui.control.NumericEditField
        Label_10                  matlab.ui.control.Label
        EditField_16              matlab.ui.control.NumericEditField
        Label_9                   matlab.ui.control.Label
        EditField_15              matlab.ui.control.NumericEditField
        EditField_14              matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        RobotContorolPanel        matlab.ui.container.Panel
        EditField_25Label         matlab.ui.control.Label
        EditField_25              matlab.ui.control.NumericEditField
        EditField_24Label         matlab.ui.control.Label
        EditField_24              matlab.ui.control.NumericEditField
        EditField_23Label         matlab.ui.control.Label
        EditField_23              matlab.ui.control.NumericEditField
        EditField_22Label         matlab.ui.control.Label
        EditField_22              matlab.ui.control.NumericEditField
        EditField_21Label         matlab.ui.control.Label
        EditField_21              matlab.ui.control.NumericEditField
        EditField_20Label         matlab.ui.control.Label
        EditField_20              matlab.ui.control.NumericEditField
        Slider6                   matlab.ui.control.Slider
        Slider5                   matlab.ui.control.Slider
        Slider4                   matlab.ui.control.Slider
        Slider3                   matlab.ui.control.Slider
        Slider2                   matlab.ui.control.Slider
        Slider                    matlab.ui.control.Slider
        ABB1600Panel              matlab.ui.container.Panel
        StartTestButton           matlab.ui.control.Button
        PlotCharsButton           matlab.ui.control.Button
        MovmentDurationEditField  matlab.ui.control.NumericEditField
        DurationLabel             matlab.ui.control.Label
        StartSimulationButton     matlab.ui.control.Button
        Button2_3                 matlab.ui.control.Button
        Button2                   matlab.ui.control.Button
        Switch_2                  matlab.ui.control.Switch
        DefaultPostionButton      matlab.ui.control.Button
        StopSimilatoinButton      matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        ApiStatusLabel            matlab.ui.control.Label
        ConnectWithApiButton      matlab.ui.control.Button
    end


    properties (Access = public)
        d1=0.4865; % Description
        d2=0.150; % Description
        d3=0.700; % Description
        d4=0.600; % Description
        d5=0; % Description
        th_0=0; % Description
        th_1=0; % Description
        th_2=0; % Description
        th_3=0; % Description
        th_4=0; % Description
        th_5=0; % Description
%         A_0_1=sym(zeros(4,4));
%         A_1_2=sym(zeros(4,4));
%         A_2_3=sym(zeros(4,4)); 
%         A_3_4=sym(zeros(4,4));
%         A_4_5=sym(zeros(4,4));
%         A_5_6=sym(zeros(4,4));
%         A_0_6=sym(zeros(4,4));
        last_pos=zeros(6);
        q= zeros(6);
        joint_handlers =zeros(8);
        JacobianMatrix = zeros(6,6);
        tau;
        trigerStatus=zeros(1,6);

    end
    
    properties (Access = private)
        Property18 % Description
    end

    properties (Access = public)
        clientID % Description
        sim % Description
      
    end

    methods (Access = private)
        function [clientID,sim] = connectToApi(app)
            app.sim=remApi('remoteApi'); % using the prototype file (remoteApiProto.m)
            app.sim.simxFinish(-1); % just in case, close all opened connections
            app.clientID=app.sim.simxStart('127.0.0.1',19997,true,true,5000,5);
            if(app.clientID>-1)
             app.EditField.Value='Connected';
            
           % define the joints
           [r1,app.joint_handlers(1)]=app.sim.simxGetObjectHandle(app.clientID,'joint0', app.sim.simx_opmode_blocking);
           [r2,app.joint_handlers(2)]=app.sim.simxGetObjectHandle(app.clientID,'joint1', app.sim.simx_opmode_blocking);
           [r3,app.joint_handlers(3)]=app.sim.simxGetObjectHandle(app.clientID,'joint2', app.sim.simx_opmode_blocking);
           [r4,app.joint_handlers(4)]=app.sim.simxGetObjectHandle(app.clientID,'joint3', app.sim.simx_opmode_blocking);
           [r5,app.joint_handlers(5)]=app.sim.simxGetObjectHandle(app.clientID,'joint4', app.sim.simx_opmode_blocking);
           [r6,app.joint_handlers(6)]=app.sim.simxGetObjectHandle(app.clientID,'joint5', app.sim.simx_opmode_blocking);
           [r,app.joint_handlers(7)]=app.sim.simxGetObjectHandle(app.clientID,'Dummy',app.sim.simx_opmode_blocking);             
           [l,app.joint_handlers(8)]=app.sim.simxGetObjectHandle(app.clientID,'DummyRef',app.sim.simx_opmode_blocking);

            else
            app.EditField.Value='Faild To Connect';
            end
            
           
        end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
function [ F_0_6 ,F_0_1, F_1_2 ,F_2_3 ,F_3_4 ,F_4_5 ,F_5_6] = forwardKinematics( app,th1, th2, th3, th4, th5, th6 )
            
            F_0_1 = transformation_matrix(app,0,    0,   app.d1 , th1);  
            %Transformation matrix for frame 2 represented in frame 1
            F_1_2 = transformation_matrix(app,-90,  app.d2,   0,  (th2-90));
            %Transformation matrix for frame 3 represented in frame 2
            F_2_3 = transformation_matrix(app,0,    app.d3 , 0    ,  th3);
            %Transformation matrix for frame 4 represented in frame 3
            F_3_4 = transformation_matrix(app,-90,  0,   app.d4    , th4); 
            %Transformation matrix for frame 5 represented in frame 4
            F_4_5 = transformation_matrix(app,90,   0,   0, th5);
            %Transformation matrix for frame 6 represented in frame 5
            F_5_6 = transformation_matrix(app,-90,  0,   0   , th6+180);
            F_0_6= F_0_1*F_1_2*F_2_3*F_3_4*F_4_5*F_5_6;
            data ={[F_0_6(1,1)],[F_0_6(1,2)],[F_0_6(1,3)],[F_0_6(1,4)];[F_0_6(2,1)],[F_0_6(2,2)],[F_0_6(2,3)],[F_0_6(2,4)];[F_0_6(3,1)],[F_0_6(3,2)],[F_0_6(3,3)],[F_0_6(3,4)];[F_0_6(4,1)],[F_0_6(4,2)],[F_0_6(4,3)],[F_0_6(4,4)]};
            app.UITable.Data=data;
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% transformation_matrix Start %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% this function used to create the Transformation matrix of a certain joint depend on the template.
% inputs: DH table parametes , alpha , a ,d, th
% outputs: transofrmation matrix according to the inputs parametes
function [ t_matrix ] = transformation_matrix(app,alpha,a, d, th)

           t_matrix =   [cosd(th)              , -sind(th)             , 0               , a;
                         sind(th)*cosd(alpha)  , cosd(th)*cosd(alpha)  , -sind(alpha)    , -sind(alpha)*d;
                         sind(alpha)*sind(th)  , cosd(th)*sind(alpha)  , cosd(alpha)     , cosd(alpha)*d;
                         0                     ,  0                    , 0               , 1  
                        ];        
end
 

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% dummyCoordinat Start   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
% this function get the postion and the oriantion of the the end-effector from coppilliasim and display it in the UI table 
% inputs: null
% outputs: void , update the table in the UI
function [resylt] = dummyCoordinat(app)
%               [r,h]=app.sim.simxGetObjectHandle(app.clientID,'Dummy',app.sim.simx_opmode_blocking);             

%             [l,d]=app.sim.simxGetObjectHandle(app.clientID,'DummyRef',app.sim.simx_opmode_blocking);
              [r,joint_0_pos] = app.sim.simxGetObjectPosition(app.clientID,app.joint_handlers(7),app.joint_handlers(8), app.sim.simx_opmode_blocking );
              pause(0.1);
              [w,joint_0_ori]=app.sim.simxGetObjectOrientation(app.clientID,app.joint_handlers(7),app.joint_handlers(8), app.sim.simx_opmode_blocking);
              tformZYX = eul2tform(joint_0_ori,'XYZ');
              dummyData ={[tformZYX(1,1)],[tformZYX(1,2)],[tformZYX(1,3)],[joint_0_pos(1,1)];
                          [tformZYX(2,1)],[tformZYX(2,2)],[tformZYX(2,3)],[joint_0_pos(1,2)];
                          [tformZYX(3,1)],[tformZYX(3,2)],[tformZYX(3,3)],[joint_0_pos(1,3)];
                          [tformZYX(4,1)],[tformZYX(4,2)],[tformZYX(4,3)],[tformZYX(4,4)]};
              app.UITable_4.Data=dummyData;
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   dummyCoordinat End   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%   


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Inverse_kinmatics Start   %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%  
% this function do the inverse kinmatics to the robot and display the result
% inputs: null
% outputs: void , update the table in the UI
function results = Inverse_kinmatics(app)
          syms th1 th2 th3 th4 th5 th6% Description
%          syms r_11 r_12 r_13 P_x r_21 r_22 r_23 P_y r_31 r_32 r_33 P_z
%=============================================================
            F_0_1 = transformation_matrix(app,0,    0,   app.d1 , th1);  
            %Transformation matrix for frame 2 represented in frame 1
            F_1_2 = transformation_matrix(app,-90,  app.d2,   0,  (th2-90));
            %Transformation matrix for frame 3 represented in frame 2
            F_2_3 = transformation_matrix(app,0,    app.d3 , 0    ,  th3);
            %Transformation matrix for frame 4 represented in frame 3
            F_3_4 = transformation_matrix(app,-90,  0,   app.d4    , th4); 
            %Transformation matrix for frame 5 represented in frame 4
            F_4_5 = transformation_matrix(app,90,   0,   0, th5);
            %Transformation matrix for frame 6 represented in frame 5
            F_5_6 = transformation_matrix(app,-90,  0,   0   , th6+180);

            sm=zeros(16,6);
            
            Tee= forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);   
            r_11 =Tee(1,1);  r_12 =Tee(1,2);  r_13 =Tee(1,3);  P_x=Tee(1,4); 
            r_21 =Tee(2,1);  r_22 =Tee(2,2);  r_23 =Tee(2,3);  P_y =Tee(2,4); 
            r_31 =Tee(3,1);  r_32 =Tee(3,2);  r_33 =Tee(3,3);  P_z =Tee(3,4); 
             F_0_4= F_0_1*F_1_2*F_2_3*F_3_4;
             X= [  r_11,r_12,r_13,P_x;
                  r_21,r_22,r_23,P_y;
                  r_31,r_32,r_33,P_z;
                  0,0,0,1];
           
              sol1=simplify(inv(F_2_3)*inv(F_1_2)*inv(F_0_1)*X);
              sag1=simplify(inv(F_2_3)*inv(F_1_2)*inv(F_0_1)*F_0_4);
              temp1 =simplify(sol1(1,4)^2+sol1(2,4)^2);
              temp2 =simplify(sag1(1,4)^2+sag1(2,4)^2);
%               disp(simplify(temp1));
%               disp(simplify(temp2));
%               %th1 find th1
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                a=-P_x;
                b=P_y;
                t1_s_1= rad2deg(atan2(-b,a));
                t1_s_2=rad2deg(atan2(b,-a));
                sm(1:8,1)=t1_s_1;
                sm(9:16,1)=t1_s_2;


               %find th3
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                c=P_y^2 - (3*P_x*cos((pi*sm(1,1))/180))/10 - (3*P_y*sin((pi*sm(1,1))/180))/10 - (973*P_z)/1000 + P_z^2 + P_x^2*cos((pi*sm(1,1))/180)^2 - P_y^2*cos((pi*sm(1,1))/180)^2 + P_x*P_y*sin((pi*sm(1,1))/90) + 1036729/4000000; 
                a=(17/20 -c)*25/21;
                t3_s_1= rad2deg(atan2(a,abs(sqrt(1-a^2))));
                t3_s_2= rad2deg(atan2(a,-abs(sqrt(1-a^2))));
                sm(1:2,3) = t3_s_1;
                sm(5:6,3) = sm(1,3);
                sm(3:4,3) = t3_s_2;
                sm(7:8,3) = sm(3,3); 

                c=P_y^2 - (3*P_x*cos((pi*sm(10,1))/180))/10 - (3*P_y*sin((pi*sm(10,1))/180))/10 - (973*P_z)/1000 + P_z^2 + P_x^2*cos((pi*sm(10,1))/180)^2 - P_y^2*cos((pi*sm(10,1))/180)^2 + P_x*P_y*sin((pi*sm(10,1))/90) + 1036729/4000000; 
                a=(17/20 -c)*25/21;
                t3_s_3= rad2deg(atan2(a,abs(sqrt(1-a^2))));
                t3_s_4= rad2deg(atan2(a,-abs(sqrt(1-a^2))));
                sm(9:10,3) = t3_s_3;
                sm(13:14,3) = sm(9,3);
                sm(11:12,3) = t3_s_4;
                sm(15:16,3) = sm(11,3);
                

                %%%%%%%%%%%%%%%%%%%%%%%%%% th2 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                b=(6811/10000 - 7*P_z/5 );                   
                a=(+ 21/100 - 7*P_x*cos((pi*sm(1,1))/180)/5-7*P_y*sin((pi*sm(1,1))/180)/5);
                c=- (973*P_z)/1000 - (3*P_x*cos((pi*sm(1,1))/180))/10 - (3*P_y*sin((pi*sm(1,1))/180))/10 + P_y^2 + P_z^2 + P_x^2*cos((pi*sm(1,1))/180)^2 - P_y^2*cos((pi*sm(1,1))/180)^2 + P_x*P_y*sin((pi*sm(1,1))/90) + 2996729/4000000;
                C= c- 9/25;
                th2_s_1= -rad2deg(atan2(b,a)+atan2(C,+abs(sqrt(a^2+b^2-C^2))));
                th2_s_2= - rad2deg(atan2(b,a)+atan2(C,-abs(sqrt(a^2+b^2-C^2))));
                sm(1:4,2)=th2_s_1;
                sm(5:8,2)=th2_s_2;
                
                b=(6811/10000 - 7*P_z/5 );                   
                a=(+ 21/100 - 7*P_x*cos((pi*sm(10,1))/180)/5-7*P_y*sin((pi*sm(10,1))/180)/5);
                c=- (973*P_z)/1000 - (3*P_x*cos((pi*sm(10,1))/180))/10 - (3*P_y*sin((pi*sm(10,1))/180))/10 + P_y^2 + P_z^2 + P_x^2*cos((pi*sm(10,1))/180)^2 - P_y^2*cos((pi*sm(10,1))/180)^2 + P_x*P_y*sin((pi*sm(10,1))/90) + 2996729/4000000;
                C= c- 9/25;
                th2_s_3= -rad2deg(atan2(b,a)+atan2(C, abs(sqrt(a^2+b^2-C^2)))) ;
                th2_s_4= -rad2deg(atan2(b,a)+atan2( C,-abs(sqrt(a^2+b^2-C^2)))) ;
                sm(9:12,2)=th2_s_3;
                sm(13:16,2)=th2_s_4;
                                  
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%th5%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%                    %th5
                   a=r_13*cos((pi*sm(1,1))/180)*cos((pi*sm(1,2))/180)*cos((pi*sm(1,3))/180) - r_33*cos((pi*sm(1,3))/180)*sin((pi*sm(1,2))/180) - r_33*cos((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) + r_23*cos((pi*sm(1,2))/180)*cos((pi*sm(1,3))/180)*sin((pi*sm(1,1))/180) - r_13*cos((pi*sm(1,1))/180)*sin((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) - r_23*sin((pi*sm(1,1))/180)*sin((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180);
                   th5_s_1=rad2deg(atan2(abs(sqrt(1-a^2)),a));

                   a=r_13*cos((pi*sm(1,1))/180)*cos((pi*sm(1,2))/180)*cos((pi*sm(1,3))/180) - r_33*cos((pi*sm(1,3))/180)*sin((pi*sm(1,2))/180) - r_33*cos((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) + r_23*cos((pi*sm(1,2))/180)*cos((pi*sm(1,3))/180)*sin((pi*sm(1,1))/180) - r_13*cos((pi*sm(1,1))/180)*sin((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) - r_23*sin((pi*sm(1,1))/180)*sin((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180);
                   th5_s_2=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
                    
                   a=r_13*cos((pi*sm(3,1))/180)*cos((pi*sm(3,2))/180)*cos((pi*sm(3,3))/180) - r_33*cos((pi*sm(3,3))/180)*sin((pi*sm(3,2))/180) - r_33*cos((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) + r_23*cos((pi*sm(3,2))/180)*cos((pi*sm(3,3))/180)*sin((pi*sm(3,1))/180) - r_13*cos((pi*sm(3,1))/180)*sin((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) - r_23*sin((pi*sm(3,1))/180)*sin((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180);
                   th5_s_3=rad2deg(atan2(abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(3,1))/180)*cos((pi*sm(3,2))/180)*cos((pi*sm(3,3))/180) - r_33*cos((pi*sm(3,3))/180)*sin((pi*sm(3,2))/180) - r_33*cos((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) + r_23*cos((pi*sm(3,2))/180)*cos((pi*sm(3,3))/180)*sin((pi*sm(3,1))/180) - r_13*cos((pi*sm(3,1))/180)*sin((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) - r_23*sin((pi*sm(3,1))/180)*sin((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180);
                   th5_s_4=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(5,1))/180)*cos((pi*sm(5,2))/180)*cos((pi*sm(5,3))/180) - r_33*cos((pi*sm(5,3))/180)*sin((pi*sm(5,2))/180) - r_33*cos((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) + r_23*cos((pi*sm(5,2))/180)*cos((pi*sm(5,3))/180)*sin((pi*sm(5,1))/180) - r_13*cos((pi*sm(5,1))/180)*sin((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) - r_23*sin((pi*sm(5,1))/180)*sin((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180);
                   th5_s_5=rad2deg(atan2(abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(5,1))/180)*cos((pi*sm(5,2))/180)*cos((pi*sm(5,3))/180) - r_33*cos((pi*sm(5,3))/180)*sin((pi*sm(5,2))/180) - r_33*cos((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) + r_23*cos((pi*sm(5,2))/180)*cos((pi*sm(5,3))/180)*sin((pi*sm(5,1))/180) - r_13*cos((pi*sm(5,1))/180)*sin((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) - r_23*sin((pi*sm(5,1))/180)*sin((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180);
                   th5_s_6=rad2deg(atan2(-abs(sqrt(1-a^2)),a));

                   a=r_13*cos((pi*sm(7,1))/180)*cos((pi*sm(7,2))/180)*cos((pi*sm(7,3))/180) - r_33*cos((pi*sm(7,3))/180)*sin((pi*sm(7,2))/180) - r_33*cos((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) + r_23*cos((pi*sm(7,2))/180)*cos((pi*sm(7,3))/180)*sin((pi*sm(7,1))/180) - r_13*cos((pi*sm(7,1))/180)*sin((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) - r_23*sin((pi*sm(7,1))/180)*sin((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180);
                   th5_s_7=rad2deg(atan2(abs(sqrt(1-a^2)),a));

                   a=r_13*cos((pi*sm(7,1))/180)*cos((pi*sm(7,2))/180)*cos((pi*sm(7,3))/180) - r_33*cos((pi*sm(7,3))/180)*sin((pi*sm(7,2))/180) - r_33*cos((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) + r_23*cos((pi*sm(7,2))/180)*cos((pi*sm(7,3))/180)*sin((pi*sm(7,1))/180) - r_13*cos((pi*sm(7,1))/180)*sin((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) - r_23*sin((pi*sm(7,1))/180)*sin((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180);
                   th5_s_8=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(9,1))/180)*cos((pi*sm(9,2))/180)*cos((pi*sm(9,3))/180) - r_33*cos((pi*sm(9,3))/180)*sin((pi*sm(9,2))/180) - r_33*cos((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) + r_23*cos((pi*sm(9,2))/180)*cos((pi*sm(9,3))/180)*sin((pi*sm(9,1))/180) - r_13*cos((pi*sm(9,1))/180)*sin((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) - r_23*sin((pi*sm(9,1))/180)*sin((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180);
                   th5_s_9=rad2deg(atan2(abs(sqrt(1-a^2)),a));

                   a=r_13*cos((pi*sm(9,1))/180)*cos((pi*sm(9,2))/180)*cos((pi*sm(9,3))/180) - r_33*cos((pi*sm(9,3))/180)*sin((pi*sm(9,2))/180) - r_33*cos((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) + r_23*cos((pi*sm(9,2))/180)*cos((pi*sm(9,3))/180)*sin((pi*sm(9,1))/180) - r_13*cos((pi*sm(9,1))/180)*sin((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) - r_23*sin((pi*sm(9,1))/180)*sin((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180);
                   th5_s_10=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
                
                   a=r_13*cos((pi*sm(11,1))/180)*cos((pi*sm(11,2))/180)*cos((pi*sm(11,3))/180) - r_33*cos((pi*sm(11,3))/180)*sin((pi*sm(11,2))/180) - r_33*cos((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) + r_23*cos((pi*sm(11,2))/180)*cos((pi*sm(11,3))/180)*sin((pi*sm(11,1))/180) - r_13*cos((pi*sm(11,1))/180)*sin((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) - r_23*sin((pi*sm(11,1))/180)*sin((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180);
                   th5_s_11=rad2deg(atan2(abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(11,1))/180)*cos((pi*sm(11,2))/180)*cos((pi*sm(11,3))/180) - r_33*cos((pi*sm(11,3))/180)*sin((pi*sm(11,2))/180) - r_33*cos((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) + r_23*cos((pi*sm(11,2))/180)*cos((pi*sm(11,3))/180)*sin((pi*sm(11,1))/180) - r_13*cos((pi*sm(11,1))/180)*sin((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) - r_23*sin((pi*sm(11,1))/180)*sin((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180);
                   th5_s_12=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(13,1))/180)*cos((pi*sm(13,2))/180)*cos((pi*sm(13,3))/180) - r_33*cos((pi*sm(13,3))/180)*sin((pi*sm(13,2))/180) - r_33*cos((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) + r_23*cos((pi*sm(13,2))/180)*cos((pi*sm(13,3))/180)*sin((pi*sm(13,1))/180) - r_13*cos((pi*sm(13,1))/180)*sin((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) - r_23*sin((pi*sm(13,1))/180)*sin((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180);
                   th5_s_13=rad2deg(atan2(abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(13,1))/180)*cos((pi*sm(13,2))/180)*cos((pi*sm(13,3))/180) - r_33*cos((pi*sm(13,3))/180)*sin((pi*sm(13,2))/180) - r_33*cos((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) + r_23*cos((pi*sm(13,2))/180)*cos((pi*sm(13,3))/180)*sin((pi*sm(13,1))/180) - r_13*cos((pi*sm(13,1))/180)*sin((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) - r_23*sin((pi*sm(13,1))/180)*sin((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180);
                   th5_s_14=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(15,1))/180)*cos((pi*sm(15,2))/180)*cos((pi*sm(15,3))/180) - r_33*cos((pi*sm(15,3))/180)*sin((pi*sm(15,2))/180) - r_33*cos((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) + r_23*cos((pi*sm(15,2))/180)*cos((pi*sm(13,3))/180)*sin((pi*sm(15,1))/180) - r_13*cos((pi*sm(15,1))/180)*sin((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) - r_23*sin((pi*sm(15,1))/180)*sin((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180);
                   th5_s_15=rad2deg(atan2(abs(sqrt(1-a^2)),a));
    
                   a=r_13*cos((pi*sm(15,1))/180)*cos((pi*sm(15,2))/180)*cos((pi*sm(15,3))/180) - r_33*cos((pi*sm(15,3))/180)*sin((pi*sm(15,2))/180) - r_33*cos((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) + r_23*cos((pi*sm(15,2))/180)*cos((pi*sm(13,3))/180)*sin((pi*sm(15,1))/180) - r_13*cos((pi*sm(15,1))/180)*sin((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) - r_23*sin((pi*sm(15,1))/180)*sin((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180);
                   th5_s_16=rad2deg(atan2(-abs(sqrt(1-a^2)),a));
    
                   sm(1,5)= th5_s_1;
                   sm(2,5)= th5_s_2;
                   sm(3,5)= th5_s_3;
                   sm(4,5)= th5_s_4;
                   sm(5,5)= th5_s_5;
                   sm(6,5)= th5_s_6;
                   sm(7,5)= th5_s_7;
                   sm(8,5)= th5_s_8;
                   sm(9,5)= th5_s_9;
                   sm(10,5)= th5_s_10;
                   sm(11,5)= th5_s_11;
                   sm(12,5)= th5_s_12;
                   sm(13,5)= th5_s_13;
                   sm(14,5)= th5_s_14;
                   sm(15,5)= th5_s_15;
                   sm(16,5)= th5_s_16;
%                    sm = wrapTo180(sm);
%                    disp(sm);
% 
% 
                % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%th4%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% 
%                  %th4
                 r13= r_33*cos((pi*sm(1,2))/180)*cos((pi*sm(1,3))/180) - r_33*sin((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) + r_13*cos((pi*sm(1,1))/180)*cos((pi*sm(1,2))/180)*sin((pi*sm(1,3))/180) + r_13*cos((pi*sm(1,1))/180)*cos((pi*sm(1,3))/180)*sin((pi*sm(1,2))/180) + r_23*cos((pi*sm(1,2))/180)*sin((pi*sm(1,1))/180)*sin((pi*sm(1,3))/180) + r_23*cos((pi*sm(1,3))/180)*sin((pi*sm(1,1))/180)*sin((pi*sm(1,2))/180);
                 r33= r_23*cos((pi*sm(1,1))/180) - r_13*sin((pi*sm(1,1))/180);
                 s5=sin((pi*sm(1,5))/180);
                 th4_s_1=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(1,4)=th4_s_1;

                 r13= r_33*cos((pi*sm(2,2))/180)*cos((pi*sm(2,3))/180) - r_33*sin((pi*sm(2,2))/180)*sin((pi*sm(2,3))/180) + r_13*cos((pi*sm(2,1))/180)*cos((pi*sm(2,2))/180)*sin((pi*sm(2,3))/180) + r_13*cos((pi*sm(2,1))/180)*cos((pi*sm(2,3))/180)*sin((pi*sm(2,2))/180) + r_23*cos((pi*sm(2,2))/180)*sin((pi*sm(2,1))/180)*sin((pi*sm(2,3))/180) + r_23*cos((pi*sm(2,3))/180)*sin((pi*sm(2,1))/180)*sin((pi*sm(2,2))/180);
                 r33= r_23*cos((pi*sm(2,1))/180) - r_13*sin((pi*sm(2,1))/180);
                 s5=sin((pi*sm(2,5))/180);
                 th4_s_2=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(2,4)=th4_s_2;
                 r13= r_33*cos((pi*sm(3,2))/180)*cos((pi*sm(3,3))/180) - r_33*sin((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) + r_13*cos((pi*sm(3,1))/180)*cos((pi*sm(3,2))/180)*sin((pi*sm(3,3))/180) + r_13*cos((pi*sm(3,1))/180)*cos((pi*sm(3,3))/180)*sin((pi*sm(3,2))/180) + r_23*cos((pi*sm(3,2))/180)*sin((pi*sm(3,1))/180)*sin((pi*sm(3,3))/180) + r_23*cos((pi*sm(3,3))/180)*sin((pi*sm(3,1))/180)*sin((pi*sm(3,2))/180);
                 r33= r_23*cos((pi*sm(3,1))/180) - r_13*sin((pi*sm(3,1))/180);
                 s5=sin((pi*sm(3,5))/180);
                 th4_s_3=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(3,4)=th4_s_3;
                 r13= r_33*cos((pi*sm(4,2))/180)*cos((pi*sm(4,3))/180) - r_33*sin((pi*sm(4,2))/180)*sin((pi*sm(4,3))/180) + r_13*cos((pi*sm(4,1))/180)*cos((pi*sm(4,2))/180)*sin((pi*sm(4,3))/180) + r_13*cos((pi*sm(4,1))/180)*cos((pi*sm(4,3))/180)*sin((pi*sm(4,2))/180) + r_23*cos((pi*sm(4,2))/180)*sin((pi*sm(4,1))/180)*sin((pi*sm(4,3))/180) + r_23*cos((pi*sm(4,3))/180)*sin((pi*sm(4,1))/180)*sin((pi*sm(4,2))/180);
                 r33= r_23*cos((pi*sm(4,1))/180) - r_13*sin((pi*sm(4,1))/180);
                 s5=sin((pi*sm(4,5))/180);
                 th4_s_4=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(4,4)=th4_s_4;
                 r13= r_33*cos((pi*sm(5,2))/180)*cos((pi*sm(5,3))/180) - r_33*sin((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) + r_13*cos((pi*sm(5,1))/180)*cos((pi*sm(5,2))/180)*sin((pi*sm(5,3))/180) + r_13*cos((pi*sm(5,1))/180)*cos((pi*sm(5,3))/180)*sin((pi*sm(5,2))/180) + r_23*cos((pi*sm(5,2))/180)*sin((pi*sm(5,1))/180)*sin((pi*sm(5,3))/180) + r_23*cos((pi*sm(5,3))/180)*sin((pi*sm(5,1))/180)*sin((pi*sm(5,2))/180);
                 r33= r_23*cos((pi*sm(5,1))/180) - r_13*sin((pi*sm(5,1))/180);
                 s5=sin((pi*sm(5,5))/180);
                 th4_s_5=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(5,4)=th4_s_5;
                 r13= r_33*cos((pi*sm(6,2))/180)*cos((pi*sm(6,3))/180) - r_33*sin((pi*sm(6,2))/180)*sin((pi*sm(6,3))/180) + r_13*cos((pi*sm(6,1))/180)*cos((pi*sm(6,2))/180)*sin((pi*sm(6,3))/180) + r_13*cos((pi*sm(6,1))/180)*cos((pi*sm(6,3))/180)*sin((pi*sm(6,2))/180) + r_23*cos((pi*sm(6,2))/180)*sin((pi*sm(6,1))/180)*sin((pi*sm(6,3))/180) + r_23*cos((pi*sm(6,3))/180)*sin((pi*sm(6,1))/180)*sin((pi*sm(6,2))/180);
                 r33= r_23*cos((pi*sm(6,1))/180) - r_13*sin((pi*sm(6,1))/180);
                 s5=sin((pi*sm(6,5))/180);
                 th4_s_6=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(6,4)=th4_s_6;
                 r13= r_33*cos((pi*sm(7,2))/180)*cos((pi*sm(7,3))/180) - r_33*sin((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) + r_13*cos((pi*sm(7,1))/180)*cos((pi*sm(7,2))/180)*sin((pi*sm(7,3))/180) + r_13*cos((pi*sm(7,1))/180)*cos((pi*sm(7,3))/180)*sin((pi*sm(7,2))/180) + r_23*cos((pi*sm(7,2))/180)*sin((pi*sm(7,1))/180)*sin((pi*sm(7,3))/180) + r_23*cos((pi*sm(7,3))/180)*sin((pi*sm(7,1))/180)*sin((pi*sm(7,2))/180);
                 r33= r_23*cos((pi*sm(7,1))/180) - r_13*sin((pi*sm(7,1))/180);
                 s5=sin((pi*sm(7,5))/180);
                 th4_s_7=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(7,4)=th4_s_7;
                 r13= r_33*cos((pi*sm(8,2))/180)*cos((pi*sm(8,3))/180) - r_33*sin((pi*sm(8,2))/180)*sin((pi*sm(8,3))/180) + r_13*cos((pi*sm(8,1))/180)*cos((pi*sm(8,2))/180)*sin((pi*sm(8,3))/180) + r_13*cos((pi*sm(8,1))/180)*cos((pi*sm(8,3))/180)*sin((pi*sm(8,2))/180) + r_23*cos((pi*sm(8,2))/180)*sin((pi*sm(8,1))/180)*sin((pi*sm(8,3))/180) + r_23*cos((pi*sm(8,3))/180)*sin((pi*sm(8,1))/180)*sin((pi*sm(8,2))/180);
                 r33= r_23*cos((pi*sm(8,1))/180) - r_13*sin((pi*sm(8,1))/180);
                 s5=sin((pi*sm(8,5))/180);
                 th4_s_8=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(8,4)=th4_s_8;
                 r13= r_33*cos((pi*sm(9,2))/180)*cos((pi*sm(9,3))/180) - r_33*sin((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) + r_13*cos((pi*sm(9,1))/180)*cos((pi*sm(9,2))/180)*sin((pi*sm(9,3))/180) + r_13*cos((pi*sm(9,1))/180)*cos((pi*sm(9,3))/180)*sin((pi*sm(9,2))/180) + r_23*cos((pi*sm(9,2))/180)*sin((pi*sm(9,1))/180)*sin((pi*sm(9,3))/180) + r_23*cos((pi*sm(9,3))/180)*sin((pi*sm(9,1))/180)*sin((pi*sm(9,2))/180);
                 r33= r_23*cos((pi*sm(9,1))/180) - r_13*sin((pi*sm(9,1))/180);
                 s5=sin((pi*sm(9,5))/180);
                 th4_s_9=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(9,4)=th4_s_9;
                 r13= r_33*cos((pi*sm(10,2))/180)*cos((pi*sm(10,3))/180) - r_33*sin((pi*sm(10,2))/180)*sin((pi*sm(10,3))/180) + r_13*cos((pi*sm(10,1))/180)*cos((pi*sm(10,2))/180)*sin((pi*sm(10,3))/180) + r_13*cos((pi*sm(10,1))/180)*cos((pi*sm(10,3))/180)*sin((pi*sm(10,2))/180) + r_23*cos((pi*sm(10,2))/180)*sin((pi*sm(10,1))/180)*sin((pi*sm(10,3))/180) + r_23*cos((pi*sm(10,3))/180)*sin((pi*sm(10,1))/180)*sin((pi*sm(10,2))/180);
                 r33= r_23*cos((pi*sm(10,1))/180) - r_13*sin((pi*sm(10,1))/180);
                 s5=sin((pi*sm(10,5))/180);
                 th4_s_10=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(10,4)=th4_s_10;
                 r13= r_33*cos((pi*sm(11,2))/180)*cos((pi*sm(11,3))/180) - r_33*sin((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) + r_13*cos((pi*sm(11,1))/180)*cos((pi*sm(11,2))/180)*sin((pi*sm(11,3))/180) + r_13*cos((pi*sm(11,1))/180)*cos((pi*sm(11,3))/180)*sin((pi*sm(11,2))/180) + r_23*cos((pi*sm(11,2))/180)*sin((pi*sm(11,1))/180)*sin((pi*sm(11,3))/180) + r_23*cos((pi*sm(11,3))/180)*sin((pi*sm(11,1))/180)*sin((pi*sm(11,2))/180);
                 r33= r_23*cos((pi*sm(11,1))/180) - r_13*sin((pi*sm(11,1))/180);
                 s5=sin((pi*sm(11,5))/180);
                 th4_s_11=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(11,4)=th4_s_11;
                 r13= r_33*cos((pi*sm(12,2))/180)*cos((pi*sm(12,3))/180) - r_33*sin((pi*sm(12,2))/180)*sin((pi*sm(12,3))/180) + r_13*cos((pi*sm(12,1))/180)*cos((pi*sm(12,2))/180)*sin((pi*sm(12,3))/180) + r_13*cos((pi*sm(12,1))/180)*cos((pi*sm(12,3))/180)*sin((pi*sm(12,2))/180) + r_23*cos((pi*sm(12,2))/180)*sin((pi*sm(12,1))/180)*sin((pi*sm(12,3))/180) + r_23*cos((pi*sm(12,3))/180)*sin((pi*sm(12,1))/180)*sin((pi*sm(12,2))/180);
                 r33= r_23*cos((pi*sm(12,1))/180) - r_13*sin((pi*sm(12,1))/180);
                 s5=sin((pi*sm(12,5))/180);
                 th4_s_12=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(12,4)=th4_s_12;
                 r13= r_33*cos((pi*sm(13,2))/180)*cos((pi*sm(13,3))/180) - r_33*sin((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) + r_13*cos((pi*sm(13,1))/180)*cos((pi*sm(13,2))/180)*sin((pi*sm(13,3))/180) + r_13*cos((pi*sm(13,1))/180)*cos((pi*sm(13,3))/180)*sin((pi*sm(13,2))/180) + r_23*cos((pi*sm(13,2))/180)*sin((pi*sm(13,1))/180)*sin((pi*sm(13,3))/180) + r_23*cos((pi*sm(13,3))/180)*sin((pi*sm(13,1))/180)*sin((pi*sm(13,2))/180);
                 r33= r_23*cos((pi*sm(13,1))/180) - r_13*sin((pi*sm(13,1))/180);
                 s5=sin((pi*sm(13,5))/180);
                 th4_s_13=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(13,4)=th4_s_13;
                 r13= r_33*cos((pi*sm(14,2))/180)*cos((pi*sm(14,3))/180) - r_33*sin((pi*sm(14,2))/180)*sin((pi*sm(14,3))/180) + r_13*cos((pi*sm(14,1))/180)*cos((pi*sm(14,2))/180)*sin((pi*sm(14,3))/180) + r_13*cos((pi*sm(14,1))/180)*cos((pi*sm(14,3))/180)*sin((pi*sm(14,2))/180) + r_23*cos((pi*sm(14,2))/180)*sin((pi*sm(14,1))/180)*sin((pi*sm(14,3))/180) + r_23*cos((pi*sm(14,3))/180)*sin((pi*sm(14,1))/180)*sin((pi*sm(14,2))/180);
                 r33= r_23*cos((pi*sm(14,1))/180) - r_13*sin((pi*sm(14,1))/180);
                 s5=sin((pi*sm(14,5))/180);
                 th4_s_14=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(14,4)=th4_s_14;
                 r13= r_33*cos((pi*sm(15,2))/180)*cos((pi*sm(15,3))/180) - r_33*sin((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) + r_13*cos((pi*sm(15,1))/180)*cos((pi*sm(15,2))/180)*sin((pi*sm(15,3))/180) + r_13*cos((pi*sm(15,1))/180)*cos((pi*sm(15,3))/180)*sin((pi*sm(15,2))/180) + r_23*cos((pi*sm(15,2))/180)*sin((pi*sm(15,1))/180)*sin((pi*sm(15,3))/180) + r_23*cos((pi*sm(15,3))/180)*sin((pi*sm(15,1))/180)*sin((pi*sm(15,2))/180);
                 r33= r_23*cos((pi*sm(15,1))/180) - r_13*sin((pi*sm(15,1))/180);
                 s5=sin((pi*sm(15,5))/180);
                 th4_s_15=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(15,4)=th4_s_15;
                 r13= r_33*cos((pi*sm(16,2))/180)*cos((pi*sm(16,3))/180) - r_33*sin((pi*sm(16,2))/180)*sin((pi*sm(16,3))/180) + r_13*cos((pi*sm(16,1))/180)*cos((pi*sm(16,2))/180)*sin((pi*sm(16,3))/180) + r_13*cos((pi*sm(16,1))/180)*cos((pi*sm(16,3))/180)*sin((pi*sm(16,2))/180) + r_23*cos((pi*sm(16,2))/180)*sin((pi*sm(16,1))/180)*sin((pi*sm(16,3))/180) + r_23*cos((pi*sm(16,3))/180)*sin((pi*sm(16,1))/180)*sin((pi*sm(16,2))/180);
                 r33= r_23*cos((pi*sm(16,1))/180) - r_13*sin((pi*sm(16,1))/180);
                 s5=sin((pi*sm(16,5))/180);
                 th4_s_16=rad2deg(atan2(r33/s5,-r13/s5));
                 sm(16,4)=th4_s_16;
    
%                 %th6 
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                sol=inv(F_0_1*F_1_2*F_2_3)*X;
                 sag=F_3_4*F_4_5*F_5_6;
                 disp(sol);
                 disp(sag);

                 r21=- (r_11*(cos((pi*sm(1,1))/180)*cos((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180) + cos((pi*sm(1,1))/180)*sin((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2) - (r_31*(cos((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180) - sin((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2) - (r_21*sin((pi*sm(1,1))/180)*(cos((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180) + sin((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(1,1))/180)*cos((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180) + cos((pi*sm(1,1))/180)*sin((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2) - (r_32*(cos((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180) - sin((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2) - (r_22*sin((pi*sm(1,1))/180)*(cos((pi*sm(1,3))/180)*sin((pi*(sm(1,2) - 90))/180) + sin((pi*sm(1,3))/180)*cos((pi*(sm(1,2) - 90))/180)))/(cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*cos((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + cos((pi*sm(1,3))/180)^2*sin((pi*sm(1,1))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*cos((pi*(sm(1,2) - 90))/180)^2 + sin((pi*sm(1,1))/180)^2*sin((pi*sm(1,3))/180)^2*sin((pi*(sm(1,2) - 90))/180)^2);
                 s5=sin((pi*sm(1,5))/180);
                 th6_s_1= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(1,6)=th6_s_1;

                 r21=- (r_11*(cos((pi*sm(2,1))/180)*cos((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180) + cos((pi*sm(2,1))/180)*sin((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2) - (r_31*(cos((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180) - sin((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2) - (r_21*sin((pi*sm(2,1))/180)*(cos((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180) + sin((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(2,1))/180)*cos((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180) + cos((pi*sm(2,1))/180)*sin((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2) - (r_32*(cos((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180) - sin((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2) - (r_22*sin((pi*sm(2,1))/180)*(cos((pi*sm(2,3))/180)*sin((pi*(sm(2,2) - 90))/180) + sin((pi*sm(2,3))/180)*cos((pi*(sm(2,2) - 90))/180)))/(cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*cos((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + cos((pi*sm(2,3))/180)^2*sin((pi*sm(2,1))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*cos((pi*(sm(2,2) - 90))/180)^2 + sin((pi*sm(2,1))/180)^2*sin((pi*sm(2,3))/180)^2*sin((pi*(sm(2,2) - 90))/180)^2);
                 s5=sin((pi*sm(2,5))/180);
                 th6_s_2= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(2,6)=th6_s_2;
              
                
                 r21=- (r_11*(cos((pi*sm(3,1))/180)*cos((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180) + cos((pi*sm(3,1))/180)*sin((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2) - (r_31*(cos((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180) - sin((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2) - (r_21*sin((pi*sm(3,1))/180)*(cos((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180) + sin((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(3,1))/180)*cos((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180) + cos((pi*sm(3,1))/180)*sin((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2) - (r_32*(cos((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180) - sin((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2) - (r_22*sin((pi*sm(3,1))/180)*(cos((pi*sm(3,3))/180)*sin((pi*(sm(3,2) - 90))/180) + sin((pi*sm(3,3))/180)*cos((pi*(sm(3,2) - 90))/180)))/(cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*cos((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + cos((pi*sm(3,3))/180)^2*sin((pi*sm(3,1))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*cos((pi*(sm(3,2) - 90))/180)^2 + sin((pi*sm(3,1))/180)^2*sin((pi*sm(3,3))/180)^2*sin((pi*(sm(3,2) - 90))/180)^2);
                 s5=sin((pi*sm(3,5))/180);
                 th6_s_3= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(3,6)=th6_s_3;

                 r21=- (r_11*(cos((pi*sm(4,1))/180)*cos((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180) + cos((pi*sm(4,1))/180)*sin((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2) - (r_31*(cos((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180) - sin((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2) - (r_21*sin((pi*sm(4,1))/180)*(cos((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180) + sin((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(4,1))/180)*cos((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180) + cos((pi*sm(4,1))/180)*sin((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2) - (r_32*(cos((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180) - sin((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2) - (r_22*sin((pi*sm(4,1))/180)*(cos((pi*sm(4,3))/180)*sin((pi*(sm(4,2) - 90))/180) + sin((pi*sm(4,3))/180)*cos((pi*(sm(4,2) - 90))/180)))/(cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*cos((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + cos((pi*sm(4,3))/180)^2*sin((pi*sm(4,1))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*cos((pi*(sm(4,2) - 90))/180)^2 + sin((pi*sm(4,1))/180)^2*sin((pi*sm(4,3))/180)^2*sin((pi*(sm(4,2) - 90))/180)^2);
                 s5=sin((pi*sm(4,5))/180);
                 th6_s_4= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(4,6)=th6_s_4;

                 r21=- (r_11*(cos((pi*sm(5,1))/180)*cos((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180) + cos((pi*sm(5,1))/180)*sin((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2) - (r_31*(cos((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180) - sin((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2) - (r_21*sin((pi*sm(5,1))/180)*(cos((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180) + sin((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(5,1))/180)*cos((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180) + cos((pi*sm(5,1))/180)*sin((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2) - (r_32*(cos((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180) - sin((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2) - (r_22*sin((pi*sm(5,1))/180)*(cos((pi*sm(5,3))/180)*sin((pi*(sm(5,2) - 90))/180) + sin((pi*sm(5,3))/180)*cos((pi*(sm(5,2) - 90))/180)))/(cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*cos((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + cos((pi*sm(5,3))/180)^2*sin((pi*sm(5,1))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*cos((pi*(sm(5,2) - 90))/180)^2 + sin((pi*sm(5,1))/180)^2*sin((pi*sm(5,3))/180)^2*sin((pi*(sm(5,2) - 90))/180)^2);
                 s5=sin((pi*sm(5,5))/180);
                 th6_s_5= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(5,6)=th6_s_5;

                 r21=- (r_11*(cos((pi*sm(6,1))/180)*cos((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180) + cos((pi*sm(6,1))/180)*sin((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2) - (r_31*(cos((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180) - sin((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2) - (r_21*sin((pi*sm(6,1))/180)*(cos((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180) + sin((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(6,1))/180)*cos((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180) + cos((pi*sm(6,1))/180)*sin((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2) - (r_32*(cos((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180) - sin((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2) - (r_22*sin((pi*sm(6,1))/180)*(cos((pi*sm(6,3))/180)*sin((pi*(sm(6,2) - 90))/180) + sin((pi*sm(6,3))/180)*cos((pi*(sm(6,2) - 90))/180)))/(cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*cos((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + cos((pi*sm(6,3))/180)^2*sin((pi*sm(6,1))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*cos((pi*(sm(6,2) - 90))/180)^2 + sin((pi*sm(6,1))/180)^2*sin((pi*sm(6,3))/180)^2*sin((pi*(sm(6,2) - 90))/180)^2);
                 s5=sin((pi*sm(6,5))/180);
                 th6_s_6= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(6,6)=th6_s_6;

                 r21=- (r_11*(cos((pi*sm(7,1))/180)*cos((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180) + cos((pi*sm(7,1))/180)*sin((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2) - (r_31*(cos((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180) - sin((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2) - (r_21*sin((pi*sm(7,1))/180)*(cos((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180) + sin((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(7,1))/180)*cos((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180) + cos((pi*sm(7,1))/180)*sin((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2) - (r_32*(cos((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180) - sin((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2) - (r_22*sin((pi*sm(7,1))/180)*(cos((pi*sm(7,3))/180)*sin((pi*(sm(7,2) - 90))/180) + sin((pi*sm(7,3))/180)*cos((pi*(sm(7,2) - 90))/180)))/(cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*cos((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + cos((pi*sm(7,3))/180)^2*sin((pi*sm(7,1))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*cos((pi*(sm(7,2) - 90))/180)^2 + sin((pi*sm(7,1))/180)^2*sin((pi*sm(7,3))/180)^2*sin((pi*(sm(7,2) - 90))/180)^2);
                 s5=sin((pi*sm(7,5))/180);
                 th6_s_7= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(7,6)=th6_s_7;

                 r21=- (r_11*(cos((pi*sm(8,1))/180)*cos((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180) + cos((pi*sm(8,1))/180)*sin((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2) - (r_31*(cos((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180) - sin((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2) - (r_21*sin((pi*sm(8,1))/180)*(cos((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180) + sin((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(8,1))/180)*cos((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180) + cos((pi*sm(8,1))/180)*sin((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2) - (r_32*(cos((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180) - sin((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2) - (r_22*sin((pi*sm(8,1))/180)*(cos((pi*sm(8,3))/180)*sin((pi*(sm(8,2) - 90))/180) + sin((pi*sm(8,3))/180)*cos((pi*(sm(8,2) - 90))/180)))/(cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*cos((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + cos((pi*sm(8,3))/180)^2*sin((pi*sm(8,1))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*cos((pi*(sm(8,2) - 90))/180)^2 + sin((pi*sm(8,1))/180)^2*sin((pi*sm(8,3))/180)^2*sin((pi*(sm(8,2) - 90))/180)^2);
                 s5=sin((pi*sm(8,5))/180);
                 th6_s_8= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(8,6)=th6_s_8;

                 r21=- (r_11*(cos((pi*sm(9,1))/180)*cos((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180) + cos((pi*sm(9,1))/180)*sin((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2) - (r_31*(cos((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180) - sin((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2) - (r_21*sin((pi*sm(9,1))/180)*(cos((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180) + sin((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(9,1))/180)*cos((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180) + cos((pi*sm(9,1))/180)*sin((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2) - (r_32*(cos((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180) - sin((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2) - (r_22*sin((pi*sm(9,1))/180)*(cos((pi*sm(9,3))/180)*sin((pi*(sm(9,2) - 90))/180) + sin((pi*sm(9,3))/180)*cos((pi*(sm(9,2) - 90))/180)))/(cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*cos((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + cos((pi*sm(9,3))/180)^2*sin((pi*sm(9,1))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*cos((pi*(sm(9,2) - 90))/180)^2 + sin((pi*sm(9,1))/180)^2*sin((pi*sm(9,3))/180)^2*sin((pi*(sm(9,2) - 90))/180)^2);
                 s5=sin((pi*sm(9,5))/180);
                 th6_s_9= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(9,6)=th6_s_9;

                 r21=- (r_11*(cos((pi*sm(10,1))/180)*cos((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180) + cos((pi*sm(10,1))/180)*sin((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2) - (r_31*(cos((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180) - sin((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2) - (r_21*sin((pi*sm(10,1))/180)*(cos((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180) + sin((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(10,1))/180)*cos((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180) + cos((pi*sm(10,1))/180)*sin((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2) - (r_32*(cos((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180) - sin((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2) - (r_22*sin((pi*sm(10,1))/180)*(cos((pi*sm(10,3))/180)*sin((pi*(sm(10,2) - 90))/180) + sin((pi*sm(10,3))/180)*cos((pi*(sm(10,2) - 90))/180)))/(cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*cos((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + cos((pi*sm(10,3))/180)^2*sin((pi*sm(10,1))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*cos((pi*(sm(10,2) - 90))/180)^2 + sin((pi*sm(10,1))/180)^2*sin((pi*sm(10,3))/180)^2*sin((pi*(sm(10,2) - 90))/180)^2);
                 s5=sin((pi*sm(10,5))/180);
                 th6_s_10= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(10,6)=th6_s_10;

                 r21=- (r_11*(cos((pi*sm(11,1))/180)*cos((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180) + cos((pi*sm(11,1))/180)*sin((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2) - (r_31*(cos((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180) - sin((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2) - (r_21*sin((pi*sm(11,1))/180)*(cos((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180) + sin((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(11,1))/180)*cos((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180) + cos((pi*sm(11,1))/180)*sin((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2) - (r_32*(cos((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180) - sin((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2) - (r_22*sin((pi*sm(11,1))/180)*(cos((pi*sm(11,3))/180)*sin((pi*(sm(11,2) - 90))/180) + sin((pi*sm(11,3))/180)*cos((pi*(sm(11,2) - 90))/180)))/(cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*cos((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + cos((pi*sm(11,3))/180)^2*sin((pi*sm(11,1))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*cos((pi*(sm(11,2) - 90))/180)^2 + sin((pi*sm(11,1))/180)^2*sin((pi*sm(11,3))/180)^2*sin((pi*(sm(11,2) - 90))/180)^2);
                 s5=sin((pi*sm(11,5))/180);
                 th6_s_11= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(11,6)=th6_s_11;

                 r21=- (r_11*(cos((pi*sm(12,1))/180)*cos((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180) + cos((pi*sm(12,1))/180)*sin((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2) - (r_31*(cos((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180) - sin((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2) - (r_21*sin((pi*sm(12,1))/180)*(cos((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180) + sin((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(12,1))/180)*cos((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180) + cos((pi*sm(12,1))/180)*sin((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2) - (r_32*(cos((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180) - sin((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2) - (r_22*sin((pi*sm(12,1))/180)*(cos((pi*sm(12,3))/180)*sin((pi*(sm(12,2) - 90))/180) + sin((pi*sm(12,3))/180)*cos((pi*(sm(12,2) - 90))/180)))/(cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*cos((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + cos((pi*sm(12,3))/180)^2*sin((pi*sm(12,1))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*cos((pi*(sm(12,2) - 90))/180)^2 + sin((pi*sm(12,1))/180)^2*sin((pi*sm(12,3))/180)^2*sin((pi*(sm(12,2) - 90))/180)^2);
                 s5=sin((pi*sm(12,5))/180);
                 th6_s_12= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(12,6)=th6_s_12;
                
                 r21=- (r_11*(cos((pi*sm(13,1))/180)*cos((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180) + cos((pi*sm(13,1))/180)*sin((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2) - (r_31*(cos((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180) - sin((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2) - (r_21*sin((pi*sm(13,1))/180)*(cos((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180) + sin((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(13,1))/180)*cos((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180) + cos((pi*sm(13,1))/180)*sin((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2) - (r_32*(cos((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180) - sin((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2) - (r_22*sin((pi*sm(13,1))/180)*(cos((pi*sm(13,3))/180)*sin((pi*(sm(13,2) - 90))/180) + sin((pi*sm(13,3))/180)*cos((pi*(sm(13,2) - 90))/180)))/(cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*cos((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + cos((pi*sm(13,3))/180)^2*sin((pi*sm(13,1))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*cos((pi*(sm(13,2) - 90))/180)^2 + sin((pi*sm(13,1))/180)^2*sin((pi*sm(13,3))/180)^2*sin((pi*(sm(13,2) - 90))/180)^2);
                 s5=sin((pi*sm(13,5))/180);
                 th6_s_13= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(13,6)=th6_s_13;

                 r21=- (r_11*(cos((pi*sm(14,1))/180)*cos((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180) + cos((pi*sm(14,1))/180)*sin((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2) - (r_31*(cos((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180) - sin((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2) - (r_21*sin((pi*sm(14,1))/180)*(cos((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180) + sin((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(14,1))/180)*cos((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180) + cos((pi*sm(14,1))/180)*sin((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2) - (r_32*(cos((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180) - sin((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2) - (r_22*sin((pi*sm(14,1))/180)*(cos((pi*sm(14,3))/180)*sin((pi*(sm(14,2) - 90))/180) + sin((pi*sm(14,3))/180)*cos((pi*(sm(14,2) - 90))/180)))/(cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*cos((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + cos((pi*sm(14,3))/180)^2*sin((pi*sm(14,1))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*cos((pi*(sm(14,2) - 90))/180)^2 + sin((pi*sm(14,1))/180)^2*sin((pi*sm(14,3))/180)^2*sin((pi*(sm(14,2) - 90))/180)^2);
                 s5=sin((pi*sm(14,5))/180);
                 th6_s_14= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(14,6)=th6_s_14;

                 r21=- (r_11*(cos((pi*sm(15,1))/180)*cos((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180) + cos((pi*sm(15,1))/180)*sin((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2) - (r_31*(cos((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180) - sin((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2) - (r_21*sin((pi*sm(15,1))/180)*(cos((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180) + sin((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(15,1))/180)*cos((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180) + cos((pi*sm(15,1))/180)*sin((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2) - (r_32*(cos((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180) - sin((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2) - (r_22*sin((pi*sm(15,1))/180)*(cos((pi*sm(15,3))/180)*sin((pi*(sm(15,2) - 90))/180) + sin((pi*sm(15,3))/180)*cos((pi*(sm(15,2) - 90))/180)))/(cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*cos((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + cos((pi*sm(15,3))/180)^2*sin((pi*sm(15,1))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*cos((pi*(sm(15,2) - 90))/180)^2 + sin((pi*sm(15,1))/180)^2*sin((pi*sm(15,3))/180)^2*sin((pi*(sm(15,2) - 90))/180)^2);
                 s5=sin((pi*sm(15,5))/180);
                 th6_s_15= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(15,6)=th6_s_15;

                 r21=- (r_11*(cos((pi*sm(16,1))/180)*cos((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180) + cos((pi*sm(16,1))/180)*sin((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2) - (r_31*(cos((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180) - sin((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2) - (r_21*sin((pi*sm(16,1))/180)*(cos((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180) + sin((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2);
                 r22=- (r_12*(cos((pi*sm(16,1))/180)*cos((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180) + cos((pi*sm(16,1))/180)*sin((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2) - (r_32*(cos((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180) - sin((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2) - (r_22*sin((pi*sm(16,1))/180)*(cos((pi*sm(16,3))/180)*sin((pi*(sm(16,2) - 90))/180) + sin((pi*sm(16,3))/180)*cos((pi*(sm(16,2) - 90))/180)))/(cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*cos((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + cos((pi*sm(16,3))/180)^2*sin((pi*sm(16,1))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*cos((pi*(sm(16,2) - 90))/180)^2 + sin((pi*sm(16,1))/180)^2*sin((pi*sm(16,3))/180)^2*sin((pi*(sm(16,2) - 90))/180)^2);
                 s5=sin((pi*sm(16,5))/180);
                 th6_s_16= rad2deg(atan2(r22/s5,-r21/s5));
                 sm(16,6)=th6_s_16;

                 sm = wrapTo180(sm);
                
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                  T1=  forwardKinematics(app,sm(1,1),sm(1,2),sm(1,3),sm(1,4),sm(1,5),sm(1,6))-Tee;
                  T2=  forwardKinematics(app,sm(2,1),sm(2,2),sm(2,3),sm(2,4),sm(2,5),sm(2,6))-Tee;
                  T3=  forwardKinematics(app,sm(3,1),sm(3,2),sm(3,3),sm(3,4),sm(3,5),sm(3,6))-Tee;
                  T4=  forwardKinematics(app,sm(4,1),sm(4,2),sm(4,3),sm(4,4),sm(4,5),sm(4,6))-Tee;
                  T5=  forwardKinematics(app,sm(5,1),sm(5,2),sm(5,3),sm(5,4),sm(5,5),sm(5,6))-Tee;
                  T6=  forwardKinematics(app,sm(6,1),sm(6,2),sm(6,3),sm(6,4),sm(6,5),sm(6,6))-Tee;
                  T7=  forwardKinematics(app,sm(7,1),sm(7,2),sm(7,3),sm(7,4),sm(7,5),sm(7,6))-Tee;
                  T8=  forwardKinematics(app,sm(8,1),sm(8,2),sm(8,3),sm(8,4),sm(8,5),sm(8,6))-Tee;
                  T9=  forwardKinematics(app,sm(9,1),sm(9,2),sm(9,3),sm(9,4),sm(9,5),sm(9,6))-Tee;
                  T10= forwardKinematics(app,sm(10,1),sm(10,2),sm(10,3),sm(10,4),sm(10,5),sm(10,6))-Tee;
                  T11= forwardKinematics(app,sm(11,1),sm(11,2),sm(11,3),sm(11,4),sm(11,5),sm(11,6))-Tee;
                  T12= forwardKinematics(app,sm(12,1),sm(12,2),sm(12,3),sm(12,4),sm(12,5),sm(12,6))-Tee;
                  T13= forwardKinematics(app,sm(13,1),sm(13,2),sm(13,3),sm(13,4),sm(13,5),sm(13,6))-Tee;
                  T14= forwardKinematics(app,sm(14,1),sm(14,2),sm(14,3),sm(14,4),sm(14,5),sm(14,6))-Tee; 
                  T15= forwardKinematics(app,sm(15,1),sm(15,2),sm(15,3),sm(15,4),sm(15,5),sm(15,6))-Tee;
                  T16= forwardKinematics(app,sm(16,1),sm(16,2),sm(16,3),sm(16,4),sm(16,5),sm(16,6))-Tee;
                  possible_sol=zeros(1,16);
                 
               if sum(abs(T1),'all')<0.00001 && sm(1,1)<=180 && sm(1,1)>=-180 &&  sm(1,2)>=-110 && sm(1,2)<=63 && sm(1,3)>=-235 && sm(1,3)<=55 && sm(1,4)>=-180 && sm(1,4)<=180 && sm(1,5)<=115 && sm(1,5)>=-115 && sm(1,6)<=180 && sm(1,6)>=-180
                   possible_sol(1)=1;
               end
              
               if sum(abs(T2),'all')<0.00001 && sm(2,1)<=180 && sm(2,1)>=-180 &&  sm(2,2)>=-110 && sm(2,2)<=63 && sm(2,3)>=-235 && sm(2,3)<=55 && sm(2,4)>=-180 && sm(2,4)<=180 && sm(2,5)<=115 && sm(2,5)>=-115 && sm(2,6)<=180 && sm(2,6)>=-180
                   possible_sol(2)=1;
               end
               
               if sum(abs(T3),'all')<0.00001 && sm(3,1)<=180 && sm(3,1)>=-180 &&  sm(3,2)>=-110 && sm(3,2)<=63 && sm(3,3)>=-235 && sm(3,3)<=55 && sm(3,4)>=-180 && sm(3,4)<=180 && sm(3,5)<=115 && sm(3,5)>=-115 && sm(3,6)<=180 && sm(3,6)>=-180
                   possible_sol(3)=1;
               end
               
               if sum(abs(T4),'all')<0.00001 && sm(4,1)<=180 && sm(4,1)>=-180 &&  sm(4,2)>=-110 && sm(4,2)<=63 && sm(4,3)>=-235 && sm(4,3)<=55 && sm(4,4)>=-180 && sm(4,4)<=180 && sm(4,5)<=115 && sm(4,5)>=-115 && sm(4,6)<=180 && sm(4,6)>=-180
                   possible_sol(4)=1;
               end
               
               if sum(abs(T5),'all')<0.00001 && sm(5,1)<=180 && sm(5,1)>=-180 &&  sm(5,2)>=-110 && sm(5,2)<=63 && sm(5,3)>=-235 && sm(5,3)<=55 && sm(5,4)>=-180 && sm(5,4)<=180 && sm(5,5)<=115 && sm(5,5)>=-115 && sm(5,6)<=180 && sm(5,6)>=-180
                   possible_sol(5)=1;
               end
               
               if sum(abs(T6),'all')<0.00001 && sm(6,1)<=180 && sm(6,1)>=-180 &&  sm(6,2)>=-110 && sm(6,2)<=63 && sm(6,3)>=-235 && sm(6,3)<=55 && sm(6,4)>=-180 && sm(6,4)<=180 && sm(6,5)<=115 && sm(6,5)>=-115 && sm(6,6)<=180 && sm(6,6)>=-180
                   possible_sol(6)=1;
               end
               
               if sum(abs(T7),'all')<0.00001 && sm(7,1)<=180 && sm(7,1)>=-180 &&  sm(7,2)>=-110 && sm(7,2)<=63 && sm(7,3)>=-235 && sm(7,3)<=55 && sm(7,4)>=-180 && sm(7,4)<=180 && sm(7,5)<=115 && sm(7,5)>=-115 && sm(7,6)<=180 && sm(7,6)>=-180
                   possible_sol(7)=1;
               end
               
               if sum(abs(T8),'all')<0.00001 && sm(8,1)<=180 && sm(8,1)>=-180 &&  sm(8,2)>=-110 && sm(8,2)<=63 && sm(8,3)>=-235 && sm(8,3)<=55 && sm(8,4)>=-180 && sm(8,4)<=180 && sm(8,5)<=115 && sm(8,5)>=-115 && sm(8,6)<=180 && sm(8,6)>=-180
                   possible_sol(8)=1;
               end
               
               if sum(abs(T9),'all')<0.00001 && sm(9,1)<=180 && sm(9,1)>=-180 &&  sm(9,2)>=-110 && sm(9,2)<=63 && sm(9,3)>=-235 && sm(9,3)<=55 && sm(9,4)>=-180 && sm(9,4)<=180 && sm(9,5)<=115 && sm(9,5)>=-115 && sm(9,6)<=180 && sm(9,6)>=-180
                   possible_sol(9)=1;
               end
               
               if sum(abs(T10),'all')<0.00001 && sm(10,1)<=180 && sm(10,1)>=-180 &&  sm(10,2)>=-110 && sm(10,2)<=63 && sm(10,3)>=-235 && sm(10,3)<=55 && sm(10,4)>=-180 && sm(10,4)<=180 && sm(10,5)<=115 && sm(10,5)>=-115 && sm(10,6)<=180 && sm(10,6)>=-180
                   possible_sol(10)=1;
               end
               
               if sum(abs(T11),'all')<0.00001 && sm(11,1)<=180 && sm(11,1)>=-180 &&  sm(11,2)>=-110 && sm(11,2)<=63 && sm(11,3)>=-235 && sm(11,3)<=55 && sm(11,4)>=-180 && sm(11,4)<=180 && sm(11,5)<=115 && sm(11,5)>=-115 && sm(11,6)<=180 && sm(11,6)>=-180
                   possible_sol(11)=1;
               end
               if sum(abs(T12),'all')<0.00001 && sm(12,1)<=180 && sm(12,1)>=-180 &&  sm(12,2)>=-110 && sm(12,2)<=63 && sm(12,3)>=-235 && sm(12,3)<=55 && sm(12,4)>=-180 && sm(12,4)<=180 && sm(12,5)<=115 && sm(12,5)>=-115 && sm(12,6)<=180 && sm(12,6)>=-180
                   possible_sol(12)=1;
               end
               
               if sum(abs(T13),'all')<0.00001 && sm(13,1)<=180 && sm(13,1)>=-180 &&  sm(13,2)>=-110 && sm(13,2)<=63 && sm(13,3)>=-235 && sm(13,3)<=55 && sm(13,4)>=-180 && sm(13,4)<=180 && sm(13,5)<=115 && sm(13,5)>=-115 && sm(13,6)<=180 && sm(13,6)>=-180
                   possible_sol(13)=1;
               end
               
               if sum(abs(T14),'all')<0.00001 && sm(14,1)<=180 && sm(14,1)>=-180 &&  sm(14,2)>=-110 && sm(14,2)<=63 && sm(14,3)>=-235 && sm(14,3)<=55 && sm(14,4)>=-180 && sm(14,4)<=180 && sm(14,5)<=115 && sm(14,5)>=-115 && sm(14,6)<=180 && sm(14,6)>=-180
                   possible_sol(14)=1;
               end
               
               if sum(abs(T15),'all')<0.00001 && sm(15,1)<=180 && sm(15,1)>=-180 &&  sm(15,2)>=-110 && sm(15,2)<=63 && sm(15,3)>=-235 && sm(15,3)<=55 && sm(15,4)>=-180 && sm(15,4)<=180 && sm(15,5)<=115 && sm(15,5)>=-115 && sm(15,6)<=180 && sm(15,6)>=-180
                   possible_sol(15)=1;
               end
               
               if sum(abs(T16),'all')<0.00001 && sm(16,1)<=180 && sm(16,1)>=-180 &&  sm(16,2)>=-110 && sm(16,2)<=63 && sm(16,3)>=-235 && sm(16,3)<=55 && sm(16,4)>=-180 && sm(16,4)<=180 && sm(16,5)<=115 && sm(16,5)>=-115 && sm(16,6)<=180 && sm(16,6)>=-180
                   possible_sol(16)=1;
               end
                
               %%% possible solutions
               sol_indices = zeros(1,sum(possible_sol));
               j=1;
               for i=1:16
                   if possible_sol(i)==1
                        sol_indices(j)=i;
                       j=j+1;
                   end
               end
        
                disp(sol_indices);
              
               %% coy solution 
               rsm = zeros(length(sol_indices),6);
               for i=1:length(sol_indices)
                   rsm(i,:)=sm(sol_indices(i),:);
               end

               pos_diff = zeros(1,length(sol_indices));
               j=1;
               disp(rsm);
               disp(app.last_pos(:,1));
               for k=1:length(sol_indices)
                   switch sol_indices(k)
                       case 1
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 2
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 3
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 4
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 5
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 6
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 7
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 8
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                        case 9
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 10
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 11
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 12
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                        case 13
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 14
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 15
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                       case 16
                           pos_diff(j)=sum(abs(rsm(k,:)-app.last_pos(:,1).'));
                           j=j+1;
                   end
               end
               % pos_dif
               [~,I]=min(pos_diff);
               ck_number =0 ;
               ck_number = sol_indices(I);
               if ck_number ~= 0 
                   switch ck_number
                       case 1
                           ck = sm(1,:);
                       case 2
                           ck = sm(2,:);
                       case 3
                           ck = sm(3,:);
                       case 4
                           ck = sm(4,:);
                       case 5
                           ck = sm(5,:);
                       case 6
                           ck = sm(6,:);
                       case 7
                           ck = sm(7,:);
                       case 8
                           ck = sm(8,:);
                       case 9
                           ck = sm(9,:);
                       case 10
                           ck = sm(10,:);
                       case 11
                           ck = sm(11,:);
                       case 12
                           ck = sm(12,:);
                       case 13
                           ck = sm(13,:);
                       case 14
                           ck = sm(14,:);
                       case 15
                           ck = sm(15,:);
                       case 16
                           ck = sm(16,:);    
                   end
        %           data ={ck(1,1) ; ck(1,2);ck(1,3);ck(1,4);ck(1,5);ck(1,6)};
                    app.EditField_14.Value=double(ck(1,1));
                    app.EditField_15.Value=double(ck(1,2));
                    app.EditField_16.Value=double(ck(1,3));
                    app.EditField_17.Value=double(ck(1,4));
                    app.EditField_18.Value=double(ck(1,5));
                    app.EditField_19.Value=double(ck(1,6));
        %             app.UITable2.Data=data;  
               end
end
        
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     yorunge Start        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%       
function konum = yorunge(app, q_init, q_final)

            J = [];
            t_total = double(app.MovmentDurationEditField.Value);
            dt = 0.01;
            t =dt*(0:t_total*(1/dt));

            q1 = zeros(1,length(t));
            q2 = zeros(1,length(t));
            q3 = zeros(1,length(t));
            q4 = zeros(1,length(t));
            q5 = zeros(1,length(t));
            q6 = zeros(1,length(t));

            q1d = zeros(1,length(t));
            q2d = zeros(1,length(t));
            q3d = zeros(1,length(t));
            q4d = zeros(1,length(t));
            q5d = zeros(1,length(t));
            q6d = zeros(1,length(t));

            q1dd = zeros(1,length(t));
            q2dd = zeros(1,length(t));
            q3dd = zeros(1,length(t));
            q4dd = zeros(1,length(t));
            q5dd = zeros(1,length(t));
            q6dd = zeros(1,length(t));

            %1 eklem
            s0_q1 = q_init(1,1);
            s1_q1 = 0;
            s2_q1 = (3*(q_final(1,1)-q_init(1,1)))/t_total^2;
            s3_q1 = -(2*(q_final(1,1)-q_init(1,1)))/t_total^3;
        
            %2 eklem
            s0_q2 = q_init(2,1);
            s1_q2 = 0;
            s2_q2 = (3*(q_final(2,1)-q_init(2,1)))/t_total^2;
            s3_q2 = -(2*(q_final(2,1)-q_init(2,1)))/t_total^3;
        
            %3 eklem
            s0_q3 = q_init(3,1);
            s1_q3 = 0;
            s2_q3 = (3*(q_final(3,1)-q_init(3,1)))/t_total^2;
            s3_q3 = -(2*(q_final(3,1)-q_init(3,1)))/t_total^3;
        
            %4 eklem
            s0_q4 = q_init(4,1);
            s1_q4 = 0;
            s2_q4 = (3*(q_final(4,1)-q_init(4,1)))/t_total^2;
            s3_q4 = -(2*(q_final(4,1)-q_init(4,1)))/t_total^3;
        
            %5 eklem
            s0_q5 = q_init(5,1);
            s1_q5 = 0;
            s2_q5 = (3*(q_final(5,1)-q_init(5,1)))/t_total^2;
            s3_q5 = -(2*(q_final(5,1)-q_init(5,1)))/t_total^3;
        
            %6 eklem
            s0_q6 = q_init(6,1);
            s1_q6 = 0;
            s2_q6 = (3*(q_final(6,1)-q_init(6,1)))/t_total^2;
            s3_q6 = -(2*(q_final(6,1)-q_init(6,1)))/t_total^3;

            for i=1:length(t)
                q1(i) = s0_q1 + s1_q1*t(i) + s2_q1*t(i)^2 + s3_q1*t(i)^3;
                q1d(i) = s1_q1 + 2*s2_q1*t(i) + 3*s3_q1*t(i)^2;
                q1dd(i) = 2*s2_q1 + 6*s3_q1*t(i);
        
                q2(i) = s0_q2 + s1_q2*t(i) + s2_q2*t(i)^2 + s3_q2*t(i)^3;
                q2d(i) = s1_q2 + 2*s2_q2*t(i) + 3*s3_q2*t(i)^2;
                q2dd(i) = 2*s2_q2 + 6*s3_q2*t(i);
        
                q3(i) = s0_q3 + s1_q3*t(i) + s2_q3*t(i)^2 + s3_q3*t(i)^3;
                q3d(i) = s1_q3 + 2*s2_q3*t(i) + 3*s3_q3*t(i)^2;
                q3dd(i) = 2*s2_q3 + 6*s3_q3*t(i);
        
                q4(i) = s0_q4 + s1_q4*t(i) + s2_q4*t(i)^2 + s3_q4*t(i)^3;
                q4d(i) = s1_q4 + 2*s2_q4*t(i) + 3*s3_q4*t(i)^2;
                q4dd(i) = 2*s2_q4 + 6*s3_q4*t(i);
        
                q5(i) = s0_q5 + s1_q5*t(i) + s2_q5*t(i)^2 + s3_q5*t(i)^3;
                q5d(i) = s1_q5 + 2*s2_q5*t(i) + 3*s3_q5*t(i)^2;
                q5dd(i) = 2*s2_q5 + 6*s3_q5*t(i);
        
                q6(i) = s0_q6 + s1_q6*t(i) + s2_q6*t(i)^2 + s3_q6*t(i)^3;
                q6d(i) = s1_q6 + 2*s2_q6*t(i) + 3*s3_q6*t(i)^2;
                q6dd(i) = 2*s2_q6 + 6*s3_q6*t(i);
            end
             workingMode=app.Switch_2.Value;
            if  isequal(workingMode,'Kinamatics')
             moveKinamatics(app,q1,q2,q3,q4,q5,q6,q_final);
            else
             moveDynamics(app,q1,q2,q3,q4,q5,q6,q1d,q2d,q3d,q4d,q5d,q6d,t,q_final,q1dd,q2dd,q3dd,q4dd,q5dd,q6dd);
            end

        
end  
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     yorunge End        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%             


%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     NewtonEuler Start        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%                
function tau = NewtonEuler(app,th1,th2,th3,th4,th5,th6,th1d,th2d,th3d,th4d,th5d,th6d,th1dd,th2dd,th3dd,th4dd,th5dd,th6dd)
                 calcJoint1Torque();

%                 % syms d1 d2 d3 d4 
%                 % syms th1 th2 th3 th4 th5 th6
%                 % syms thd_1 thd_2 thd_3 thd_4 thd_5 thd_6
%                 % syms thdd_1 thdd_2 thdd_3 thdd_4 thdd_5 thdd_6 qqd

                th=[th1;th2;th3;th4;th5;th6];
                thd=[th1d; th2d;th3d; th4d;th5d;th6d];
                thdd=[th1dd;th2dd;th3dd;th4dd;th5dd;th6dd];
                
                % syms sx sy sz
                % syms ax ay az
                % syms ex ey ez
                % syms fx fy fz
                % syms wx wy wz
                % syms tx ty tz
                % syms m1 m2 m3 m4 m5 m6
                
                % syms g
                sx=-0.100364; sy=0.000507; sz=0.061573;
                ax=0.052211; ay=-0.011660; az=-0.142980;
                ex=0.304043; ey=0.000218; ez=-0.184238;
                fx=0.020923; fy=0.013891; fz=-0.013326;
                wx=-0.000169; wy=0.003837; wz=-0.121384;
                tx=0.000000; ty=0.000000; tz=-0.004000;
                m1=226.441377; m2=313.501540; m3=81.805119;m4=128.425694;m5=22.665251;m6=1.424947;
                g=-9.8;
                dh=[sx sy sz; ax ay az; ex ey ez;fx fy fz;wx wy wz;tx ty tz]';
                m=[m1 m2 m3 m4 m5 m6];
                
                
                % syms s11 s12 s13 s21 s22 s23 s31 s32 s33
                % syms a11 a12 a13 a21 a22 a23 a31 a32 a33
                % syms e11 e12 e13 e21 e22 e23 e31 e32 e33
                % syms f11 f12 f13 f21 f22 f23 f31 f32 f33
                % syms w11 w12 w13 w21 w22 w23 w31 w32 w33
                % syms t11 t12 t13 t21 t22 t23 t31 t32 t33
                %%%
                s11=3.830460; s12=-0.010630; s13=-0.316423;
                s21=-0.010630; s22=6.601434; s23=0.008453;
                s31=-0.316423; s32=0.008453; s33=9.832628;
                %%%
                a11=6.635873; a12=-0.515106; a13=2.112206;
                a21=-0.515106; a22=10.738892; a23=-0.740529;
                a31=2.112206; a32=-0.740529; a33=7.557603;
                %%%
                e11=0.204182; e12=0.005822; e13=0.270148;
                e21=0.005822; e22=5.113105; e23=0.000385;
                e31=0.270148; e32=0.000385; e33=5.168176;
                %%%
                f11=0.905998; f12=0.000020; f13=0.021725; 
                f21=-0.063051; f22=2.043683; f23=-0.068049;
                f31=0.021725; f32=-0.068049; f33=2.062896;
                %%%
                w11=0.214934; w12=0.1; w13=-0.000343;
                w21= 0.000020; w22=0.207801; w23=-0.002772;
                w31=-0.000343; w32=-0.002772; w33=0.037412;
                %%%
                t11=0.000758; t12=-0.000010; t13=0.000000;
                t21=-0.000010; t22=0.000929; t23=0.000000;
                t31=0.000000; t32=0.000000; t33=0.001180;
                
                
                I= zeros(3,3,6);
                I(:,:,1)=[s11 s12 s13;s21 s22 s23;s31 s32 s33];
                I(:,:,2)=[a11 a12 a13 ;a21 a22 a23 ;a31 a32 a33];
                I(:,:,3)=[e11 e12 e13;e21 e22 e23;e31 e32 e33];
                I(:,:,4)=[f11 f12 f13;f21 f22 f23;f31 f32 f33];
                I(:,:,5)=[w11 w12 w13;w21 w22 w23;w31 w32 w33];
                I(:,:,6)=[t11 t12 t13;t21 t22 t23;t31 t32 t33];
                
                [ ~ ,T_0_1, T_1_2 ,T_2_3 ,T_3_4 ,T_4_5 ,T_5_6]=forwardKinematics( app,th1, th2, th3, th4, th5, th6 );
                T= zeros(4,4,6);
                R= zeros(3,3,6);
                
                R_01 = T_0_1(1:3,1:3);
                R_12 = T_1_2(1:3,1:3);
                R_23 = T_2_3(1:3,1:3);
                R_34 = T_3_4(1:3,1:3);
                R_45 = T_4_5(1:3,1:3);
                R_56 = T_5_6(1:3,1:3);
                R=cat(3,R_01,R_12,R_23,R_34,R_45,R_56);
                %disp(R);
                %T=[T_0_1,T_1_2,T_2_3,T_3_4,T_4_5,T_5_6];
                T=cat(3,T_0_1,T_1_2,T_2_3,T_3_4,T_4_5,T_5_6);
                P1 = T_0_1(1:3,4);
                P2 = T_0_1(1:3,4);
                P3 = T_0_1(1:3,4);
                P4 = T_0_1(1:3,4);
                P5 = T_0_1(1:3,4);
                P6 = T_0_1(1:3,4);
                P=[P1,P2,P3,P4,P5,P6];
                oT= sym(zeros(4,4,6));
                oR = sym(zeros(3,3,6));
                oT(:,:,1) = T(1);
                
                for i=2:6
                    oT(:,:,i)=simplify(oT(:,:,1)*T(i));
                end
                
                oR =oT(1:3,1:3,:);
                o =sym(zeros(3,6));
                od = sym(zeros(3,6));
                vd =sym(zeros(3,6));
                vdc = sym(zeros(3,6));
                F = sym(zeros(3,6));
                N= sym(zeros(3,6));
                f= sym(zeros(3,6));
                n= sym(zeros(3,6));
                Tau = sym(zeros(1,6));
                Zi = [0;0;1];
                N_DOFS=6;
                
                for i = 1 : N_DOFS
                    if i>1
                        disp(R(:,:,i));
                        o(:,i)= R(:,:,i)'*(o(:,i-1) + Zi.*thd(i,1));
                        od(:,i)=R(:,:,i)'*(od(:,i-1)+Zi.*thdd(i,1)+ cross(o(:,i-1),Zi.*thd(i,1)));
                        vd(:,i)=R(:,:,i)'*vd(:,i-1)+ cross(od(:,i),P(:,i))+ cross(o(:,i),cross(o(:,i),P(:,i)));
                 
                    else
                          disp(R(:,:,i));
                        o(:,i)= R(:,:,i)'*(Zi.*thd(i,1));
                        od(:,i)=R(:,:,i)'*(Zi.*thdd(i,1));
                        vd(:,i)=R(:,:,i)'*[0;0;g]+cross(od(:,i),P(:,i))+ cross(od(:,i),cross(od(:,i),P(:,i+1)));
                            
                    end
                end
                
                for i=N_DOFS:-1:1
                   vdc(:,i) = vd(:,i)+ cross(od(:,i),dh(1:3,i))+cross(o(:,i),cross(o(:,i),dh(1:3,i)));
                   F(:,i) = m(1,i)*vdc(:,i);
                   N(:,i) = I(:,:,i)*od(:,i)+cross(o(:,i),I(:,:,i)*o(:,i));
                    if i<N_DOFS
                       n(:,i)=R(:,:,i)*(n(:,i+1) +cross(R(:,:,i)'*P(:,i),f(:,i+1)))+cross(dh(:,i)+P(:,i),F(:,i))+N(:,i);
                       f(:,i)=R(:,:,i)*f(:,i+1) +F(:,i);
                    else
                      n(:,i) = cross(dh(:,i)+P(:,i),F(:,i))+N(:,i);
                      f(:,i) =F(:,i);
                    end
                
                    tau(:,i)=n(:,i)'*R(:,:,i)*Zi;
                    
                end
end    
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%     NewtonEuler End        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
function results = moveKinamatics(app,q1,q2,q3,q4,q5,q6,q_final)
             for i = 1:length(q1)
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(1),(q1(i)*pi)/180, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(2), (q2(i)*pi)/180, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(3), (q3(i)*pi)/180, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(4), (q4(i)*pi)/180, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(5), (q5(i)*pi)/180, app.sim.simx_opmode_streaming);
                app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(6), (q6(i)*pi)/180, app.sim.simx_opmode_streaming);
%               [ncode,line_vel,ang_vel]=app.sim.simxGetObjectVelocity(app.clientID,app.joint_handlers(7),app.sim.simx_opmode_streaming);
            end
            pause(2);
            app.last_pos = q_final;
            app.q = q_final;
        end
        

       function results = moveDynamics(app,q1,q2,q3,q4,q5,q6,q1d,q2d,q3d,q4d,q5d,q6d,t,q_final,q1dd,q2dd,q3dd,q4dd,q5dd,q6dd)
            Jacobian = []; 
            for i = 1:length(q1)
                
              
                [ ~,A_0_1, A_1_2, A_2_3, A_3_4, A_4_5, A_5_6 ] = forwardKinematics( app,q1(i), q2(i), q3(i), q4(i), q5(i), q6(i));
                A_0_2 = A_0_1*A_1_2;
                A_0_3 = A_0_1*A_1_2*A_2_3;
                A_0_4 = A_0_1*A_1_2*A_2_3*A_3_4;
                A_0_5 = A_0_1*A_1_2*A_2_3*A_3_4*A_4_5;
                A_0_6 = A_0_1*A_1_2*A_2_3*A_3_4*A_4_5*A_5_6;
                
                
                z = zeros(3,7);
                Jw = zeros(3,6);
                
                z0 = [0;0;1];
                z1 = A_0_1(1:3,1:3)*[0;0;1];
                z2 = A_0_2(1:3,1:3)*[0;0;1];
                z3 = A_0_3(1:3,1:3)*[0;0;1];
                z4 = A_0_4(1:3,1:3)*[0;0;1];
                z5 = A_0_5(1:3,1:3)*[0;0;1];
                z6 = A_0_6(1:3,1:3)*[0;0;1];
                
                origin0 = [0;0;0];
                origin1 = A_0_1(1:3,4);
                origin2 = A_0_2(1:3,4);
                origin3 = A_0_3(1:3,4);
                origin4 = A_0_4(1:3,4);
                origin5 = A_0_5(1:3,4);
                origin6 = A_0_6(1:3,4);
               
                J(:,1) = [cross(z0,(origin6 - origin0));z0];
                J(:,2) = [cross(z1,(origin6 - origin1));z1];
                J(:,3) = [cross(z2,(origin6 - origin2));z2];
                J(:,4) = [cross(z3,(origin6 - origin3));z3];
                J(:,5) = [cross(z4,(origin6 - origin4));z4];
                J(:,6) = [cross(z5,(origin6 - origin5));z5];
                

                vx=J(1,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                vy=J(2,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                vz=J(3,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                wx=J(4,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                wy=J(5,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                wz=J(6,1:6)*[q1d;q2d;q3d;q4d;q5d;q6d];
                Jacobian = cat(3, Jacobian, J);

             end
              app.last_pos = q_final;
              app.q = q_final;
              
              test=zeros(length(q1),6);
              for i=1:length(q1)
                    test(i,:)=InverseDynamicsRVC(app,q1(i),q2(i),q3(i),q4(i),q5(i),q6(i),q1d(i),q2d(i),q3d(i),q4d(i),q5d(i),q6d(i),q1dd(i),q2dd(i),q3dd(i),q4dd(i),q5dd(i),q6dd(i));
              end

%               test=zeros(length(q1),6);
%               for i=1:length(q1)
%                     test(i,:)=NewtonEuler(app,q1(i),q2(i),q3(i),q4(i),q5(i),q6(i),q1d(i),q2d(i),q3d(i),q4d(i),q5d(i),q6d(i),q1dd(i),q2dd(i),q3dd(i),q4dd(i),q5dd(i),q6dd(i));
%               end

                torque =9999;
                min_torque=2000;
               
                V11(1, :) = Jacobian(1, 1, :);
                V12(2, :) = Jacobian(2, 1, :);
                V13(3, :) = Jacobian(3, 1, :);
                w11(1, :) = Jacobian(4, 1, :);
                w12(2, :) = Jacobian(5, 1, :);
                w13(3, :) = Jacobian(6, 1, :);

                V21(1, :) = Jacobian(1, 2, :);
                V22(2, :) = Jacobian(2, 2, :);
                V23(3, :) = Jacobian(3, 2, :);
                w21(1, :) = Jacobian(4, 2, :);
                w22(2, :) = Jacobian(5, 2, :);
                w23(3, :) = Jacobian(6, 2, :);

                V31(1, :) = Jacobian(1, 3, :);
                V32(2, :) = Jacobian(2, 3, :);
                V33(3, :) = Jacobian(3, 3, :);
                w31(1, :) = Jacobian(4, 3, :);
                w32(2, :) = Jacobian(5, 3, :);
                w33(3, :) = Jacobian(6, 3, :);

                V41(1, :) = Jacobian(1, 4, :);
                V42(2, :) = Jacobian(2, 4, :);
                V43(3, :) = Jacobian(3, 4, :);
                w41(1, :) = Jacobian(4, 4, :);
                w42(2, :) = Jacobian(5, 4, :);
                w43(3, :) = Jacobian(6, 4, :);

                V51(1, :) = Jacobian(1, 5, :);
                V52(2, :) = Jacobian(2, 5, :);
                V53(3, :) = Jacobian(3, 5, :);
                w51(1, :) = Jacobian(4, 5, :);
                w52(2, :) = Jacobian(5, 5, :);
                w53(3, :) = Jacobian(6, 5, :);

                V61(1, :) = Jacobian(1, 6, :);
                V62(2, :) = Jacobian(2, 6, :);
                V63(3, :) = Jacobian(3, 6, :);
                w61(1, :) = Jacobian(4, 6, :);
                w62(2, :) = Jacobian(5, 6, :);
                w63(3, :) = Jacobian(6, 6, :);
                
                for i=1:length(q1)
                    trq1=test(i,1);
                    trq2=test(i,2);
                    trq3=test(i,3);
                    trq4=test(i,4);
                    trq5=test(i,5);
                    trq6=test(i,6);
                    if trq1 <2000 || trq1 > -2000
                        trq1=min_torque;
                    end
                    if trq2 <2000 || trq2 > -2000
                        trq2=min_torque;
                    end
                    if trq3 <2000 && trq3 > -2000
                        trq3=min_torque;
                    end
                    if trq4 <2000 && trq4 > -2000
                        trq4=min_torque;
                    end
                    if trq5 <2000 && trq5 > -2000
                        trq5=min_torque;
                    end
                    if trq6 <2000 && trq6 > -2000
                        trq6=min_torque;
                    end
                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(1),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(1),abs(trq1),app.sim.simx_opmode_streaming);
        
                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(2),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(2),abs(trq2),app.sim.simx_opmode_streaming);

                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(3),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(3),abs(trq3),app.sim.simx_opmode_streaming);

                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(4),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(4),abs(trq4),app.sim.simx_opmode_streaming);

                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(5),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(5),abs(trq5),app.sim.simx_opmode_streaming);

                    app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(6),9999,app.sim.simx_opmode_streaming);
                    app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(6),abs(trq6),app.sim.simx_opmode_streaming);

                   if app.trigerStatus(1,1) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(1),(q1(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end
                   if app.trigerStatus(1,2) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(2),(q2(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end
                   if app.trigerStatus(1,3) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(3),(q3(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end
                   if app.trigerStatus(1,4) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(4),(q4(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end
                   if app.trigerStatus(1,5) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(5),(q5(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end
                   if app.trigerStatus(1,6) == 1
                        app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(6),(q6(i)*pi)/180, app.sim.simx_opmode_streaming);
                   end

                end
                app.trigerStatus= zeros(1,6);

                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(1),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(1),2000,app.sim.simx_opmode_streaming);
                
                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(2),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(2),2000,app.sim.simx_opmode_streaming);

                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(3),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(3),2000,app.sim.simx_opmode_streaming);

                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(4),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(4),2000,app.sim.simx_opmode_streaming);

                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(5),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(5),2000,app.sim.simx_opmode_streaming);

                app.sim.simxSetJointTargetVelocity(app.clientID,app.joint_handlers(6),50,app.sim.simx_opmode_streaming);
                app.sim.simxSetJointMaxForce(app.clientID,app.joint_handlers(6),2000,app.sim.simx_opmode_streaming);

              figure('visible','off');
              app.tau=test;
              fig=plot(app.UIAxes_3,t,test(:,1),'b',t,test(:,2),'--',t,test(:,3),'r',t,test(:,4),t,test(:,5),t,test(:,6));
              legend(fig,'joint1','joint2','joint3','joint4','joint5','joint6');
              fig=plot(app.UIAxes,t,vx,'b',t,vy,'--',t,vz,'r'); 
              legend(fig,'Vx','Vy','Vz');
              fig=plot(app.UIAxes_2,t,deg2rad(wx),'b',t,deg2rad(wy),'--',t,deg2rad(wz),'r');
              legend(fig,'Wx','Wy','Wz');
              %% torque of joint1
              fig=plot(app.UIAxes_6,t,test(:,1));
              legend(fig,'joint1');
              %% torque of joint2
              fig=plot(app.UIAxes_9,t,test(:,2));
              legend(fig,'joint2');
              %% torque of joint3
              fig=plot(app.UIAxes_12,t,test(:,3));
              legend(fig,'joint3');
              % torque of joint4
              fig=plot(app.UIAxes_15,t,test(:,4));
              legend(fig,'joint4');
              % torque of joint5
              fig=plot(app.UIAxes_18,t,test(:,5));
              legend(fig,'joint5');
              % torque of joint6
              fig=plot(app.UIAxes_21,t,test(:,6));
              legend(fig,'joint6');
    
            %Linear Velocities
            fig=plot(app.UIAxes_4,t,V11(1, :),'b',t,V12(2, :),'--',t,V13(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_5,t,w11(1, :),'b',t,w12(2, :),'--',t,w13(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');

        
            %Linear Velocities
            fig=plot(app.UIAxes_7,t,V21(1, :),'b',t,V22(2, :),'--',t,V23(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_8,t,w21(1, :),'b',t,w22(2, :),'--',t,w23(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');

        
            %Linear Velocities
            fig=plot(app.UIAxes_10,t,V31(1, :),'b',t,V32(2, :),'--',t,V33(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_11,t,w31(1, :),'b',t,w32(2, :),'--',t,w33(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');

        
            %Linear Velocities
            fig=plot(app.UIAxes_13,t,V41(1, :),'b',t,V42(2, :),'--',t,V43(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_14,t,w41(1, :),'b',t,w42(2, :),'--',t,w43(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');
        
            %Linear Velocities
            fig=plot(app.UIAxes_16,t,V51(1, :),'b',t,V52(2, :),'--',t,V53(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_17,t,w51(1, :),'b',t,w52(2, :),'--',t,w53(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');

            %Linear Velocities
            fig=plot(app.UIAxes_19,t,V61(1, :),'b',t,V62(2, :),'--',t,V63(3, :),'r'); 
            legend(fig,'Vx','Vy','Vz');
            fig=plot(app.UIAxes_20,t,w61(1, :),'b',t,w62(2, :),'--',t,w63(3, :),'r'); 
            legend(fig,'Wx','Wy','Wz');
                
        end
        
        function [jointTorques] = InverseDynamicsRVC(app,q1,q2,q3,q4,q5,q6,q1d,q2d,q3d,q4d,q5d,q6d,q1dd,q2dd,q3dd,q4dd,q5dd,q6dd)
            sx=0.052211; sy=-0.011660; sz=-0.142980;
            ax=0.304043; ay=0.000218; az=-0.184238;
            ex=0.020923; ey=0.013891; ez= -0.013326;
            fx=-0.000159; fy=0.003847; fz=-0.286204;
            wx=0; wy=0; wz=-0.0;
            tx=-0.002805; ty=-0.003826; tz=0.010;

            m1= 313.501540 ; m2= 81.805119; m3=128.425694;m4=22.665251;m5=1.424947;m6=0.252386;
            g=-9.8;
            
            % %%%
            s11 = 6.635873;	s12 = -0.515106;	s13 = 2.112206;
	        s21 = -0.515106;	s22 = 10.738892;	s23 = -0.740529;
        	s31 = 2.112206;	s32 = -0.740529;	s33 = 7.557603;
            %%%
            a11= 0.204182;	a12= 0.005822;	a13 = 0.270148;
	        a21 = 0.005822;	a22 = 5.113105;	a23 = 0.000385;
	        a31 = 0.270148;	a32 = 0.000385;	a33 = 5.168176;
            %%%
            e11 = 0.905998;	e12 = -0.063051;	e13= 0.021725;
            e21 = -0.063051;	e22 = 2.043683;	e23 = -0.068049;
            e31 = 0.021725;	e32= -0.068049;	e33 = 2.062896;
            %%%
        	f11 = 0.214934;	f12 = 0.000020;	f13 = -0.000343;
            f21 = 0.000020;	f22 = 0.207801;	f23 = -0.002772;
            f31 = -0.000343;	f32 = -0.002772;	f33= 0.037412;
            %%%
            w11= 0.000758; w12=0; w13=0;
            w21= 0.000020; w22=0.000929; w23=0;
            w31=0; w32=0; w33=0.001180;
            %%%
            t11 = 0.000112;	t12 = 0.000000;	t13 = -0.000004;
	        t21 = 0.000000;	t22 = 0.000113;	t23 = 0.000001;
	        t31 = -0.000004;t32 = 0.000001;	t33 = 0.000049;

            dh=[sx sy sz; ax ay az; ex ey ez;fx fy fz;wx wy wz;tx ty tz]';
            m=[m1 m2 m3 m4 m5 m6];
            I= zeros(3,3,6);
            I(:,:,1)=[s11 s12 s13;s21 s22 s23;s31 s32 s33];
            I(:,:,2)=[a11 a12 a13;a21 a22 a23;a31 a32 a33];
            I(:,:,3)=[e11 e12 e13;e21 e22 e23;e31 e32 e33];
            I(:,:,4)=[f11 f12 f13;f21 f22 f23;f31 f32 f33];
            I(:,:,5)=[w11 w12 w13;w21 w22 w23;w31 w32 w33];
            I(:,:,6)=[t11 t12 t13;t21 t22 t23;t31 t32 t33];
            qc=[q1,q2,q3,q4,q5,q6];
            qcdot=[q1d,q2d,q3d,q4d,q5d,q6d];
            qcddot=[q1dd,q2dd,q3dd,q4dd,q5dd,q6dd];
            d1=0.4865; % Description
            d2=0.150; % Description
            d3=0.700; % Description
            d4=0.600; % Description
            L(1) = createLink(0  ,d1 ,0    ,0     ,dh(:,1),m(1), I(:,:,1));
            L(2) = createLink(d2 ,0  ,-pi/2,0     ,dh(:,2),m(2), I(:,:,2));
            L(3) = createLink(d3 ,0  , 0   ,0     ,dh(:,3),m(3), I(:,:,3));
            L(4) = createLink(0  ,d4 ,-pi/2,0     ,dh(:,4),m(4), I(:,:,4));
            L(5) = createLink(0  ,0  ,pi/2 ,0     ,dh(:,5),m(5), I(:,:,5));
            L(6) = createLink(0  ,0  ,-pi/2,0     ,dh(:,6),m(6), I(:,:,6));
            L(1).isRotary=1;
            L(2).isRotary=1;
            L(3).isRotary=1;
            L(4).isRotary=1;
            L(5).isRotary=1;
            L(6).isRotary=1;
            boundary_conditions(1).base_angular_velocity=0;
            boundary_conditions(1).base_angular_acceleration=0;
            boundary_conditions(1).base_linear_acceleration=0;
            boundary_conditions(1).distal_force=0;
            boundary_conditions(1).distal_torque=0;
            
            jointTorques=zeros(6,1)';
            % Jv JvDot
            [jointTorques]=newtonEuler( L, qc, qcdot, qcddot,boundary_conditions )';
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.connectToApi();
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.q(1)=app.th_0;
            app.th_0 = app.Slider.Value;
%           [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint0', app.sim.simx_opmode_blocking);
            app.last_pos(1)=app.th_0;
            app.trigerStatus(1,1)=1;

%             th= (app.th_0*pi)/180;
%             app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(1), th, app.sim.simx_opmode_streaming);
            forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
%             displayData(app,T_temp);
            yorunge(app,app.q,app.last_pos);
            dummyCoordinat(app);
            app.EditField_20.Value=app.th_0;
           
        end

        % Button pushed function: ConnectWithApiButton
        function ConnectWithApiButtonButtonPushed(app, event)

             app.connectToApi();
            

        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            app.q(2)=app.th_1;
            app.th_1 = app.Slider2.Value;
            app.last_pos(2)=app.th_1;
%              th= (app.th_1*pi)/180;
%             [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint1', app.sim.simx_opmode_blocking);
%             app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(2), th, app.sim.simx_opmode_streaming);
            app.trigerStatus(1,2)=1;
            forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
            yorunge(app,app.q,app.last_pos);
            dummyCoordinat(app);
            app.EditField_21.Value=app.th_1;

        end

        % Value changed function: Slider3
        function Slider3ValueChanged(app, event)
            app.q(3)=app.th_2;
            app.th_2 = app.Slider3.Value;
            app.last_pos(3)=app.th_2;

%                th= (app.th_2*pi)/180;
%             [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint2', app.sim.simx_opmode_blocking);
%             app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(3), th, app.sim.simx_opmode_streaming);
            app.trigerStatus(1,3)=1;
            forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
            yorunge(app,app.q,app.last_pos);
%             displayData(app,T_temp);
            dummyCoordinat(app);
            app.EditField_22.Value=app.th_2;
            
        end

        % Value changed function: Slider4
        function Slider4ValueChanged(app, event)
             app.q(4)=app.th_3;
             app.th_3 = app.Slider4.Value;
             app.last_pos(4)=app.th_3;
%              th= (app.th_3*pi)/180;
%             [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint3', app.sim.simx_opmode_blocking);
%              app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(4), th, app.sim.simx_opmode_streaming);
             app.trigerStatus(1,4)=1;
             forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
             yorunge(app,app.q,app.last_pos);
%             displayData(app,T_temp);
             dummyCoordinat(app);
             app.EditField_23.Value=app.th_3;

        end

        % Value changed function: Slider5
        function Slider5ValueChanged(app, event)
             app.q(5)=app.th_4;
             app.th_4 = app.Slider5.Value;
             app.last_pos(5)=app.th_4;
%             [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint4', app.sim.simx_opmode_blocking);
%             app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(5), th, app.sim.simx_opmode_streaming);
             app.trigerStatus(1,5)=1;
             forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
             yorunge(app,app.q,app.last_pos);
%             displayData(app,T_temp);
             dummyCoordinat(app);
             app.EditField_24.Value=app.th_4;

        end

        % Value changed function: Slider6
        function Slider6ValueChanged(app, event)
             app.q(6)=app.th_5;
             app.th_5 = app.Slider6.Value;
             app.last_pos(6)=app.th_5;
%             [r,x]=app.sim.simxGetObjectHandle(app.clientID,'joint5', app.sim.simx_opmode_blocking);
%              app.sim.simxSetJointTargetPosition( app.clientID,app.joint_handlers(6), th, app.sim.simx_opmode_streaming);
             app.trigerStatus(1,6)=1;
             forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
             yorunge(app,app.q,app.last_pos);
%             displayData(app,T_temp);
             dummyCoordinat(app);
             app.EditField_25.Value=app.th_5;

        end

        % Button pushed function: Button2
        function Button2ButtonPushed(app, event)
            dummyCoordinat(app);
            Inverse_kinmatics(app)
        end

        % Button pushed function: StartSimulationButton
        function StartSimulationButtonPushed(app, event)
            app.sim.simxStartSimulation(app.clientID,app.sim.simx_opmode_oneshot);

              
        end

        % Display data changed function: UITable
        function UITableDisplayDataChanged(app, event)
            newDisplayData = app.UITable.DisplayData;
            newDisplayData(1,1)=12;
        end

        % Button pushed function: DefaultPostionButton
        function DefaultPostionButtonPushed(app, event)
            app.q(1)=app.th_0;
            app.q(2)=app.th_1; 
            app.q(3)=app.th_2;
            app.q(4)=app.th_3;
            app.q(5)=app.th_4;
            app.q(6)=app.th_5;
            
             app.th_0 = 0;
             app.th_1 = 0;
             app.th_2 = 0;
             app.th_3 = 0;
             app.th_4 = 0;
             app.th_5 = 0;

            app.last_pos(1)=app.th_0;
            app.last_pos(2)=app.th_1;
            app.last_pos(3)=app.th_2;
            app.last_pos(4)=app.th_3;
            app.last_pos(5)=app.th_4;
            app.last_pos(6)=app.th_5;

            pause(0.3);
            forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
            yorunge(app,app.q,app.last_pos);
            pause(1);
            dummyCoordinat(app);

            app.Slider.Value=0;
            app.Slider2.Value=0;
            app.Slider3.Value=0;
            app.Slider4.Value=0;
            app.Slider5.Value=0;
            app.Slider6.Value=0;

            app.EditField_14.Value=0;
            app.EditField_15.Value=0;
            app.EditField_16.Value=0;
            app.EditField_17.Value=0;
            app.EditField_18.Value=0;
            app.EditField_19.Value=0;

            app.EditField_20.Value=0;
            app.EditField_21.Value=0;
            app.EditField_22.Value=0;
            app.EditField_23.Value=0;
            app.EditField_24.Value=0;
            app.EditField_25.Value=0;
            workingMode=app.Switch_2.Value;
            if  isequal(workingMode,'Dynamics')
                app.trigerStatus(1,1)=1;
                app.trigerStatus(1,2)=1;
                app.trigerStatus(1,3)=1;
                app.trigerStatus(1,4)=1;
                app.trigerStatus(1,5)=1;
                app.trigerStatus(1,6)=1;
            end
%              th0= (app.th_0*pi)/180;
%              th1= (app.th_1*pi)/180;
%              th2= (app.th_2*pi)/180;
%              th3= (app.th_3*pi)/180;
%              th4= (app.th_4*pi)/180;
%              th5= (app.th_5*pi)/180;
%             [r0,x0]=app.sim.simxGetObjectHandle(app.clientID,'joint0', app.sim.simx_opmode_blocking);
%             [r1,x1]=app.sim.simxGetObjectHandle(app.clientID,'joint1', app.sim.simx_opmode_blocking);
%             [r2,x2]=app.sim.simxGetObjectHandle(app.clientID,'joint2', app.sim.simx_opmode_blocking);
%             [r3,x3]=app.sim.simxGetObjectHandle(app.clientID,'joint3', app.sim.simx_opmode_blocking);
%             [r4,x4]=app.sim.simxGetObjectHandle(app.clientID,'joint4', app.sim.simx_opmode_blocking);
%             [r5,x5]=app.sim.simxGetObjectHandle(app.clientID,'joint5', app.sim.simx_opmode_blocking);
%             pause(1);
%             app.sim.simxSetJointTargetPosition( app.clientID,x0,th0, app.sim.simx_opmode_streaming);
%             app.sim.simxSetJointTargetPosition( app.clientID,x1,th1, app.sim.simx_opmode_streaming);
%             app.sim.simxSetJointTargetPosition( app.clientID,x2,th2, app.sim.simx_opmode_streaming);
%             app.sim.simxSetJointTargetPosition( app.clientID,x3,th3, app.sim.simx_opmode_streaming);
%             app.sim.simxSetJointTargetPosition( app.clientID,x4,th4, app.sim.simx_opmode_streaming);
%             app.sim.simxSetJointTargetPosition( app.clientID,x5,th5, app.sim.simx_opmode_streaming);
%             T_temp= forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
%             displayData(app,T_temp);
%             dummyCoordinat(app);  
%             displayData(app,T_temp);
%             dummyCoordinat(app); 

        end

        % Button pushed function: Button2_3
        function Button2_3Pushed(app, event)
            
            app.q(1)=app.th_0;
            app.q(2)=app.th_1; 
            app.q(3)=app.th_2;
            app.q(4)=app.th_3;
            app.q(5)=app.th_4;
            app.q(6)=app.th_5;
            
            app.th_0=app.EditField_14.Value;
            app.th_1=app.EditField_15.Value;
            app.th_2=app.EditField_16.Value;
            app.th_3=app.EditField_17.Value;
            app.th_4=app.EditField_18.Value;
            app.th_5=app.EditField_19.Value;
           
            app.last_pos(1)=app.th_0;
            app.last_pos(2)=app.th_1;
            app.last_pos(3)=app.th_2;
            app.last_pos(4)=app.th_3;
            app.last_pos(5)=app.th_4;
            app.last_pos(6)=app.th_5;
           
            pause(0.3);
            forwardKinematics(app,app.th_0,app.th_1,app.th_2,app.th_3,app.th_4,app.th_5);
            yorunge(app,app.q,app.last_pos);
            dummyCoordinat(app);

            app.Slider.Value=app.th_0;
            app.Slider2.Value=app.th_1;
            app.Slider3.Value=app.th_2;
            app.Slider4.Value=app.th_3;
            app.Slider5.Value=app.th_4;
            app.Slider6.Value=app.th_5;

            app.EditField_20.Value=app.th_0;
            app.EditField_21.Value=app.th_1;
            app.EditField_22.Value=app.th_2;
            app.EditField_23.Value=app.th_3;
            app.EditField_24.Value=app.th_4;
            app.EditField_25.Value=app.th_5;

        end

        % Value changed function: EditField_20
        function EditField_20ValueChanged(app, event)
            value = app.EditField_20.Value;
            
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;
            app.trigerStatus=zeros(1,6);
           
        end

        % Button pushed function: StopSimilatoinButton
        function StopSimilatoinButtonPushed(app, event)
             app.sim.simxStopSimulation(app.clientID,app.sim.simx_opmode_oneshot);
        end

        % Button pushed function: StartTestButton
        function StartTestButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ABB1600Panel = uipanel(app.UIFigure);
            app.ABB1600Panel.Title = 'ABB 1600';
            app.ConnectWithApiButton = uibutton(app.ABB1600Panel, 'push');
            app.ConnectWithApiButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectWithApiButtonButtonPushed, true);
            app.ConnectWithApiButton.Text = 'Connect With Api';
            app.ApiStatusLabel = uilabel(app.ABB1600Panel);
            app.ApiStatusLabel.Text = 'Api Status';
            app.EditField = uieditfield(app.ABB1600Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Enable = 'off';
            app.EditField.Placeholder = 'Not connected';
            app.StopSimilatoinButton = uibutton(app.ABB1600Panel, 'push');
            app.StopSimilatoinButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimilatoinButtonPushed, true);
            app.StopSimilatoinButton.Text = 'Stop Similatoin';
            app.DefaultPostionButton = uibutton(app.ABB1600Panel, 'push');
            app.DefaultPostionButton.ButtonPushedFcn = createCallbackFcn(app, @DefaultPostionButtonPushed, true);
            app.DefaultPostionButton.Text = 'Default Postion';
            app.Switch_2 = uiswitch(app.ABB1600Panel, 'slider');
            app.Switch_2.Items = {'Dynamics', 'Kinamatics'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Kinamatics';
            app.Button2 = uibutton(app.ABB1600Panel, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed, true);
            app.Button2.Text = 'Calculate Invese Kinmatics';
            app.Button2_3 = uibutton(app.ABB1600Panel, 'push');
            app.Button2_3.ButtonPushedFcn = createCallbackFcn(app, @Button2_3Pushed, true);
            app.Button2_3.Text = 'Calculate Forward Kinmatics';
            app.StartSimulationButton = uibutton(app.ABB1600Panel, 'push');
            app.StartSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimulationButtonPushed, true);
            app.StartSimulationButton.Text = 'Start Simulation';
            app.DurationLabel = uilabel(app.ABB1600Panel);
            app.DurationLabel.Text = 'Movment Duration';
            app.MovmentDurationEditField = uieditfield(app.ABB1600Panel, 'numeric');
            app.MovmentDurationEditField.Value = 1;
            app.PlotCharsButton = uibutton(app.ABB1600Panel, 'push');
            app.PlotCharsButton.Text = 'Plot Chars';
            app.StartTestButton = uibutton(app.ABB1600Panel, 'push');
            app.StartTestButton.ButtonPushedFcn = createCallbackFcn(app, @StartTestButtonPushed, true);
            app.StartTestButton.Text = 'Start Test';
            app.RobotContorolPanel = uipanel(app.UIFigure);
            app.RobotContorolPanel.Title = 'Robot Contorol';
            app.Slider = uislider(app.RobotContorolPanel);
            app.Slider.Limits = [-180 180];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider2 = uislider(app.RobotContorolPanel);
            app.Slider2.Limits = [-110 63];
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider3 = uislider(app.RobotContorolPanel);
            app.Slider3.Limits = [-235 55];
            app.Slider3.ValueChangedFcn = createCallbackFcn(app, @Slider3ValueChanged, true);
            app.Slider4 = uislider(app.RobotContorolPanel);
            app.Slider4.Limits = [-180 180];
            app.Slider4.ValueChangedFcn = createCallbackFcn(app, @Slider4ValueChanged, true);
            app.Slider5 = uislider(app.RobotContorolPanel);
            app.Slider5.Limits = [-115 115];
            app.Slider5.ValueChangedFcn = createCallbackFcn(app, @Slider5ValueChanged, true);
            app.Slider5.Value = 0.05;
            app.Slider6 = uislider(app.RobotContorolPanel);
            app.Slider6.Limits = [-180 180];
            app.Slider6.ValueChangedFcn = createCallbackFcn(app, @Slider6ValueChanged, true);
            app.EditField_20 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_20.ValueDisplayFormat = '%3.2f';
            app.EditField_20.ValueChangedFcn = createCallbackFcn(app, @EditField_20ValueChanged, true);
            app.EditField_20Label = uilabel(app.RobotContorolPanel);
            app.EditField_20Label.Text = 'θ1';
            app.EditField_21 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_21.ValueDisplayFormat = '%3.2f';
            app.EditField_21Label = uilabel(app.RobotContorolPanel);
            app.EditField_21Label.Text = 'θ2';
            app.EditField_22 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_22.ValueDisplayFormat = '%3.2f';
            app.EditField_22Label = uilabel(app.RobotContorolPanel);
            app.EditField_22Label.Text = 'θ3';
            app.EditField_23 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_23.ValueDisplayFormat = '%3.2f';
            app.EditField_23Label = uilabel(app.RobotContorolPanel);
            app.EditField_23Label.Text = 'θ4';
            app.EditField_24 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_24.ValueDisplayFormat = '%3.2f';
            app.EditField_24Label = uilabel(app.RobotContorolPanel);
            app.EditField_24Label.Text = 'θ5';
            app.EditField_25 = uieditfield(app.RobotContorolPanel, 'numeric');
            app.EditField_25.ValueDisplayFormat = '%3.2f';
            app.EditField_25Label = uilabel(app.RobotContorolPanel);
            app.EditField_25Label.Text = 'θ6';
            app.InverseValuesPanel = uipanel(app.UIFigure);
            app.InverseValuesPanel.Title = 'Inverse Values';
            app.Label_8 = uilabel(app.InverseValuesPanel);
            app.Label_8.Text = 'θ1';
            app.EditField_14 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_14.ValueDisplayFormat = '%3.2f';
            app.EditField_15 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_15.ValueDisplayFormat = '%3.2f';
            app.Label_9 = uilabel(app.InverseValuesPanel);
            app.Label_9.Text = 'θ2';
            app.EditField_16 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_16.ValueDisplayFormat = '%3.2f';
            app.Label_10 = uilabel(app.InverseValuesPanel);
            app.Label_10.Text = 'θ3';
            app.EditField_17 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_17.ValueDisplayFormat = '%3.2f';
            app.Label_11 = uilabel(app.InverseValuesPanel);
            app.Label_11.Text = 'θ4';
            app.EditField_18 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_18.ValueDisplayFormat = '%3.2f';
            app.Label_12 = uilabel(app.InverseValuesPanel);
            app.Label_12.Text = 'θ5';
            app.EditField_19 = uieditfield(app.InverseValuesPanel, 'numeric');
            app.EditField_19.ValueDisplayFormat = '%3.2f';
            app.Label_13 = uilabel(app.InverseValuesPanel);
            app.Label_13.Text = 'θ6';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EndEffectorTab = uitab(app.TabGroup);
            app.EndEffectorTab.Title = 'End Effector';
            app.UIAxes = uiaxes(app.EndEffectorTab);
            title(app.UIAxes, 'End-effactor Linear Velocity')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Velocity(m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.EndEffectorTab);
            title(app.UIAxes_2, 'End-effactor Angular Velocity')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Velocity(rad)')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.EndEffectorTab);
            title(app.UIAxes_3, 'Joints Torque')
            xlabel(app.UIAxes_3, 'Time')
            ylabel(app.UIAxes_3, 'Torque')
            zlabel(app.UIAxes_3, 'Z')
            app.Joint1Tab = uitab(app.TabGroup);
            app.Joint1Tab.Title = 'Joint 1 ';
            app.UIAxes_4 = uiaxes(app.Joint1Tab);
            title(app.UIAxes_4, 'Linear Velocity')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.Joint1Tab);
            title(app.UIAxes_5, 'Angular Velocity')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.Joint1Tab);
            title(app.UIAxes_6, 'Torque')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.Joint2Tab = uitab(app.TabGroup);
            app.Joint2Tab.Title = 'Joint 2 ';
            app.UIAxes_7 = uiaxes(app.Joint2Tab);
            title(app.UIAxes_7, 'Linear Velocity')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.Joint2Tab);
            title(app.UIAxes_8, 'Angular Velocity')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.Joint2Tab);
            title(app.UIAxes_9, 'Torque')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.Joint3Tab = uitab(app.TabGroup);
            app.Joint3Tab.Title = 'Joint 3 ';
            app.UIAxes_10 = uiaxes(app.Joint3Tab);
            title(app.UIAxes_10, 'Linear Velocity')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_11 = uiaxes(app.Joint3Tab);
            title(app.UIAxes_11, 'Angular Velocity')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_12 = uiaxes(app.Joint3Tab);
            title(app.UIAxes_12, 'Torque')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.Joint4Tab = uitab(app.TabGroup);
            app.Joint4Tab.Title = 'Joint 4 ';
            app.UIAxes_13 = uiaxes(app.Joint4Tab);
            title(app.UIAxes_13, 'Linear Velocity')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_14 = uiaxes(app.Joint4Tab);
            title(app.UIAxes_14, 'Angular Velocity')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_15 = uiaxes(app.Joint4Tab);
            title(app.UIAxes_15, 'Torque')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            zlabel(app.UIAxes_15, 'Z')
            app.Joint5Tab = uitab(app.TabGroup);
            app.Joint5Tab.Title = 'Joint 5 ';
            app.UIAxes_16 = uiaxes(app.Joint5Tab);
            title(app.UIAxes_16, 'Linear Velocity')
            xlabel(app.UIAxes_16, 'X')
            ylabel(app.UIAxes_16, 'Y')
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_17 = uiaxes(app.Joint5Tab);
            title(app.UIAxes_17, 'Angular Velocity')
            xlabel(app.UIAxes_17, 'X')
            ylabel(app.UIAxes_17, 'Y')
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_18 = uiaxes(app.Joint5Tab);
            title(app.UIAxes_18, 'Torque')
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            app.Joint6Tab = uitab(app.TabGroup);
            app.Joint6Tab.Title = 'Joint 6 ';
            app.UIAxes_19 = uiaxes(app.Joint6Tab);
            title(app.UIAxes_19, 'Linear Velocity')
            xlabel(app.UIAxes_19, 'X')
            ylabel(app.UIAxes_19, 'Y')
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_20 = uiaxes(app.Joint6Tab);
            title(app.UIAxes_20, 'Angular Velocity')
            xlabel(app.UIAxes_20, 'X')
            ylabel(app.UIAxes_20, 'Y')
            zlabel(app.UIAxes_20, 'Z')
            app.UIAxes_21 = uiaxes(app.Joint6Tab);
            title(app.UIAxes_21, 'Torque')
            xlabel(app.UIAxes_21, 'X')
            ylabel(app.UIAxes_21, 'Y')
            zlabel(app.UIAxes_21, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.DisplayDataChangedFcn = createCallbackFcn(app, @UITableDisplayDataChanged, true);
            app.CalculatedTransformationMatrixLabel = uilabel(app.Panel);
            app.CalculatedTransformationMatrixLabel.Text = 'Calculated Transformation Matrix';
            app.UITable_4 = uitable(app.Panel);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.MesuredTransformationMatrixLabel = uilabel(app.Panel);
            app.MesuredTransformationMatrixLabel.Text = 'Mesured Transformation Matrix';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end